classdef Batch_Deconv_Sniffer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LogPanel                        matlab.ui.container.Panel
        logTextarea                     matlab.ui.control.TextArea
        DeconvolutionProgressPanel      matlab.ui.container.Panel
        Lamp                            matlab.ui.control.Lamp
        PendingExperimentsTextArea      matlab.ui.control.TextArea
        PendingExperimentsTextAreaLabel  matlab.ui.control.Label
        generalProgressBar              matlab.ui.container.Panel
        localProgressBar                matlab.ui.container.Panel
        DeconvolvedExperimentsTextArea  matlab.ui.control.TextArea
        DeconvolvedExperimentsTextAreaLabel  matlab.ui.control.Label
        ExperimentsloadPanel            matlab.ui.container.Panel
        StartProcessButton              matlab.ui.control.Button
        ExperimentsFolderEditField      matlab.ui.control.EditField
        ExperimentsFolderEditFieldLabel  matlab.ui.control.Label
        SelectExperimentsButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        experimentType
        RLIter
        % Triggered
        triggerTime
        badInitialFrames
        baselineEndFrame
        RegistrationMode
        % Spontaneous
        stdFactor
        numStacks
        windowOffset
        windowLength
        overlappedFrames
        
        mainFolder
        experimentsFolders

        imagesCellArray
        registeredImagesCellArray
        unFocusResults
        regCoeffs
        unbleachedImagesCellArray
        UnbleachingResults
        deltaFRegCellArray
        deconvolvedStacks
        imagesToVisualize
    end
    
    methods (Access = private)
        
        function plotExperimentsList(app, pendingList, readyList)
            app.PendingExperimentsTextArea.Value = pendingList;
            app.DeconvolvedExperimentsTextArea.Value = readyList;
        end

        function loadStacks(app, stacksInfo, stacksNumber)
            
            displayLog(app, "Loading images files... ")
            tic

            if stacksNumber > 1 % Triggered
                app.imagesCellArray = cell(stacksNumber, 1);
                for i = 1: stacksNumber
                    imageMatrixCell = getMatrixFromImages("/"+stacksInfo(i).name,...
                            stacksInfo(i).folder, 0);
                    app.imagesCellArray{i, 1} = cat(3,imageMatrixCell{:});
                end
            else % Spontaneous
                imageMatrix = getMatrixFromImages("/"+stacksInfo.name,...
                    stacksInfo.folder, 0);
                % Splitting Images
                [m, n, z] = size(imageMatrix);
                D = (z / app.numStacks) * ones(1,app.numStacks);
                app.imagesCellArray = cell(1, app.numStacks);
                
                for i = 1 : app.numStacks
                    app.imagesCellArray(i,:) = mat2cell(imageMatrix, m, n, D);
                    app.imagesCellArray(i,:) = reshape(app.imagesCellArray(i,:), [], app.numStacks);
                end
            end

            elapsedTime = toc;
            displayLog(app, "All Images loaded in memory. Elapsed time > "+ string(elapsedTime)+ " Seconds");
        end

        function registerImages(app, stacksNumber)
            displayLog(app, "Registering images... ")
            tic

            if stacksNumber > 1 % Triggered
                [app.registeredImagesCellArray, app.regCoeffs] = getRegisteredImages(app.imagesCellArray, stacksNumber, app.RegistrationMode);
            else % Spontaneous
                [app.registeredImagesCellArray, app.regCoeffs] = getRegisteredImages(app.imagesCellArray, app.numStacks, app.RegistrationMode);
                app.unFocusResults = getOutOfFocusFrames(app.registeredImagesCellArray, app.stdFactor);
            end

            elapsedTime = toc;
            displayLog(app, "All Stacks are registered. Elapsed time > "+ string(elapsedTime)+ "Seconds")
        end

        function unbleachImages(app)
            displayLog(app, "Correcting Bleaching... ")
            tic

            [app.unbleachedImagesCellArray, meanPercentDiff, allAvgBefore, ...
                allAvgAfterItrial, allAvgAfterWhole, ...
                allFits, allCoeffs] = ...
              getImagesWithoutBleaching(app.registeredImagesCellArray, ...
                    app.badInitialFrames, app.triggerTime);

            app.UnbleachingResults.avgBefore = allAvgBefore;
            app.UnbleachingResults.avgAfter = allAvgAfterItrial;
            app.UnbleachingResults.avgWhole = allAvgAfterWhole;
            app.UnbleachingResults.fits = allFits;
            app.UnbleachingResults.meanPercentDiff = meanPercentDiff;
            app.UnbleachingResults.allCoeffs = allCoeffs;
            
            elapsedTime = toc;
            displayLog(app, "Bleaching corrected. Elapsed time > "+ string(elapsedTime)+ " Seconds");
        end

        function calculateDFs(app, stacksNumber)
            displayLog(app, "Getting ΔF Images... ")
            drawnow
            tic

            if stacksNumber > 1 % Triggered
                app.deltaFRegCellArray = cell(1);
                for i = 1: stacksNumber
                    [app.deltaFRegCellArray{i}, ~, ~] = getDFMatrix(0, app.unbleachedImagesCellArray{i}, ...
                        app.badInitialFrames, app.baselineEndFrame);
                end
            else % Spontaneous
                DFImage = getDFMatrix(1, app.registeredImagesCellArray, ...
                        app.windowLength, app.windowOffset);
                
                [~, ~, z] = size(DFImage);
                app.deltaFRegCellArray = cell(1);
                cont = 1;
                step = (z / app.numStacks) + app.overlappedFrames;
                for i = 1 : step: z
                    if i == 1
                        app.deltaFRegCellArray{cont} = DFImage(:,:, i : i + step);
                    elseif cont < app.numStacks
                        app.deltaFRegCellArray{cont} = DFImage(:,:, i - app.overlappedFrames : i + step);
                    else
                        app.deltaFRegCellArray{cont} = DFImage(:,:, i - app.overlappedFrames : end);
                    end
                    cont = cont + 1;
                end
            end

            elapsedTime = toc;
            displayLog(app, "ΔF Images generated. Elapsed time > "+ string(elapsedTime)+ " Seconds");
        end

        function deconvolveImages(app, stacksNumber)
            displayLog(app, "Deconvolution Started... ")
            tic

            DFStacks = app.deltaFRegCellArray;
            iter = app.RLIter;

            if stacksNumber > 1 % Triggered
                load("data/Triggered Kernel.mat", "defaultKernel")  
                kernel = defaultKernel;
                deconvStacks = cell(stacksNumber, 1);
                parfor i = 1: stacksNumber
                    deconvStacks{i, 1} = deconvlucy(DFStacks{i}, kernel, iter);
                end
            app.deconvolvedStacks = deconvStacks;
            else % Spontaneous
                load("data/Spontaneous Kernel.mat", "kernel")
                deconvStacks = cell(app.numStacks, 1);
                parfor i = 1: app.numStacks
                    deconvStacks{i, 1} = deconvlucy(DFStacks{i}, kernel, iter);
                end
                app.deconvolvedStacks = [];
                for i = 1 : app.numStacks
                    if i < app.numStacks
                        app.deconvolvedStacks = cat(3, app.deconvolvedStacks, deconvStacks{i}(:,:,1:end-app.overlappedFrames-1));
                    else
                        app.deconvolvedStacks = cat(3, app.deconvolvedStacks, deconvStacks{i});
                    end
                end
            end

            elapsedTime = toc;
            displayLog(app, "Deconvolution Finished. Elapsed time > "+ string(elapsedTime)+ "Seconds")
        end

        function saveWorkspace(app, experiment)
            displayLog(app, "Saving workspace... ")
            tic

            regImagesCellArray = app.registeredImagesCellArray;
            deconvStacks = app.deconvolvedStacks;
            regCoeffs = app.regCoeffs;
            
            switch app.experimentType
                case "spontaneous"
                    myVars = {'experiment', 'deconvStacks', 'regImagesCellArray', 'regCoeffs'};
                case "triggered"
                    bleachData = app.UnbleachingResults;
                    imagesToVisualize.Original = getShiftedCompositeImage(app.imagesCellArray);
                    imagesToVisualize.Registered = getShiftedCompositeImage(app.registeredImagesCellArray);
                    imagesToVisualize.Template = mean(app.imagesCellArray{1}(:,:,app.badInitialFrames:app.triggerTime), 3);
                    imagesToVisualize = correctRGBBrightness(imagesToVisualize, length(app.deconvolvedStacks));
                    myVars = {'experiment', 'deconvStacks', 'regImagesCellArray', 'regCoeffs', 'bleachData', 'imagesToVisualize'};
            end
            
            mkdir("data/batch " + experiment)
            workspace = "data/batch "+ experiment +"/Results Workspace";
            save(workspace, myVars{:}, '-v7.3');

            displayLog(app, "Creating Zip file... ")
            zip("data/batch " + experiment + ".zip", "batch " + experiment + "/Results Workspace.mat", "data/");
            
            delete(workspace + ".mat")
            rmdir("data/batch " + experiment, "s")
            rmdir(app.mainFolder + "/" + experiment, "s")

            elapsedTime = toc;
            displayLog(app, "Workspace saved. Elapsed time > "+ string(elapsedTime)+ "Seconds")
        end

        function progressBarObj = initializeProgressBar(~, progressBarAx, progressTitle, barColor)
            % Set up the progress bar axis
            progressBarObj.ax = axes(progressBarAx,'Position',[.1 .4 .8 .05],'box','on','xtick',[],'ytick',[],...
                'color',[0.9375 0.9375 0.9375],'xlim',[0,1],'ylim',[0,1]); 
            title(progressBarObj.ax, progressTitle, 'Interpreter','none')
            % Create empty patch that will be updated
            progressBarObj.ph = patch(progressBarObj.ax,[0 0 0 0],[0 0 1 1],barColor);
            % Create the percent-complete text that will be updated
            progressBarObj.th = text(progressBarObj.ax,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right'); 
            drawnow
        end

        function progressBarObj = updateProgressBar(~, progressBarObj, progressTitle, counter, barLength)
            % update patch size and percentage text
            title(progressBarObj.ax, "")
            progressBarObj.ph.XData = [0 counter/barLength counter/barLength 0];
            title(progressBarObj.ax, progressTitle, 'Interpreter','none')
            progressBarObj.th.String = sprintf('%.0f%%',round(counter/barLength*100));
            drawnow % update graphics
        end

        function displayLog(app, newString)
            message = '[' + string(datetime('now')) + ']: ' + newString;
            
            if strcmp(app.logTextarea.Value,'')
                message_disp = message;
            else
                message_disp = [app.logTextarea.Value(:)', message];
            end
            app.logTextarea.Value = message_disp;
            scroll(app.logTextarea, 'bottom')
            app.UIFigure.Visible = 'on';
            drawnow
        end

        function saveLog(app)
            fid = fopen('data/LOG.txt','wt+');
            for i = 1 : length(app.logTextarea.Value)
                fprintf(fid, '%s', app.logTextarea.Value{i});
                fprintf(fid, '/n');
            end
            fclose(fid);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            paramsMap = getParametersMap();
            app.RLIter = paramsMap('RLIterations');
            app.triggerTime = paramsMap('triggerTime');
            app.badInitialFrames = paramsMap('initFrames');
            app.baselineEndFrame = paramsMap('baselineEndFrame');
            regMode = paramsMap('regMode');
            if regMode == 0
                app.RegistrationMode = 'monomodal';
            else
                app.RegistrationMode = 'multimodal';
            end
            expType = paramsMap('experimentType');
            if expType == 0
                app.experimentType = 'triggered';
            else
                app.experimentType = 'spontaneous';
            end
            app.stdFactor = paramsMap('stdFactor');
            app.numStacks = paramsMap('numStacks');
            app.windowOffset = double(paramsMap('windowOffset'));
            app.windowLength = double(paramsMap('windowLength'));
            app.overlappedFrames = double(paramsMap('overlappedFrames'));
            fid = fopen('data/LOG.txt','wt+');
            fclose(fid);
        end

        % Button pushed function: SelectExperimentsButton
        function SelectExperimentsButtonPushed(app, event)
            app.mainFolder = uigetdir;
            content = dir(fullfile(app.mainFolder,'*'));
            app.experimentsFolders = [content.isdir] & ~ismember({content.name},{'.','..'});
            app.experimentsFolders = {content(app.experimentsFolders).name};

            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';

            if ispc
                urlSplit = regexp(app.mainFolder,'/','split');
                app.ExperimentsFolderEditField.Value = string(urlSplit(end));
            elseif isunix || ismac
                urlSplit = regexp(app.mainFolder,'/','split');
                app.ExperimentsFolderEditField.Value = string(urlSplit(end));
            end

            plotExperimentsList(app, app.experimentsFolders, "");
            app.StartProcessButton.Enable = true;
        end

        % Button pushed function: StartProcessButton
        function StartProcessButtonPushed(app, event)
            displayLog(app, "---------------------------------");
            displayLog(app, "--------PROCESS STARTED----------");
            pending = app.experimentsFolders;
            ready = cell(0);
            barColor = {[0.67578 1 0.18359], [0 0.4470 0.7410]};
            generalProgressBarObj = initializeProgressBar(app, app.generalProgressBar, "Total Progress", barColor{2});
            % For Iteration over all experiments
            readyCont = 1;
            for e = 1 : length(app.experimentsFolders)
                try
                    experiment = app.experimentsFolders{e};
                    displayLog(app, "---------------------------------");
                    displayLog(app, "----------" + experiment + "-------------");
                    displayLog(app, "---------------------------------");
                    stacksInfo = dir(app.mainFolder + "/" + experiment + "/*.tif");

                    switch app.experimentType
                        case "spontaneous"
                            progressTitle = experiment + " - Loading Images";
                            localProgressBarObj = initializeProgressBar(app, app.localProgressBar, progressTitle, barColor{1});

                            % Image Loading
                            loadStacks(app, stacksInfo, stacksNumber);
                            progressTitle = experiment + " - Registering Images";
                            localProgressBarObj = updateProgressBar(app, localProgressBarObj, progressTitle, 10, 100);

                            % Image Registration and Un-Focus Detection
                            registerImages(app, stacksNumber)
                            progressTitle = experiment + " - Unbleaching Images";
                            localProgressBarObj = updateProgressBar(app, localProgressBarObj, progressTitle, 30, 100);
        
                            % DF Images Calculation
                            calculateDFs(app, stacksNumber)
                            progressTitle = experiment + " - Deconvolving Images";
                            localProgressBarObj = updateProgressBar(app, localProgressBarObj, progressTitle, 50, 100);
        
                            % Image Deconvolution
                            deconvolveImages(app, stacksNumber)
                            progressTitle = experiment + " - Saving Workspace";
                            localProgressBarObj = updateProgressBar(app, localProgressBarObj, progressTitle, 90, 100);
        
                            % Saving Workspace
                            saveWorkspace(app, experiment)
                            progressTitle = experiment + " - Finished";
                            localProgressBarObj = updateProgressBar(app, localProgressBarObj, progressTitle, 100, 100);

                        case "triggered"
                            stacksNumber = length(stacksInfo);
        
                            progressTitle = experiment + " - Loading Images";
                            localProgressBarObj = initializeProgressBar(app, app.localProgressBar, progressTitle, barColor{1});
                            % Image Loading
                            loadStacks(app, stacksInfo, stacksNumber);
                            progressTitle = experiment + " - Registering Images";
                            localProgressBarObj = updateProgressBar(app, localProgressBarObj, progressTitle, 10, 100);
        
                            % Image Registration
                            registerImages(app, stacksNumber)
                            progressTitle = experiment + " - Unbleaching Images";
                            localProgressBarObj = updateProgressBar(app, localProgressBarObj, progressTitle, 30, 100);
        
                            % Image Unbleaching
                            unbleachImages(app)
                            progressTitle = experiment + " - Getting DF Images";
                            localProgressBarObj = updateProgressBar(app, localProgressBarObj, progressTitle, 40, 100);
        
                            % DF Images Calculation
                            calculateDFs(app, stacksNumber)
                            progressTitle = experiment + " - Deconvolving Images";
                            localProgressBarObj = updateProgressBar(app, localProgressBarObj, progressTitle, 50, 100);
        
                            % Image Deconvolution
                            deconvolveImages(app, stacksNumber)
                            progressTitle = experiment + " - Saving Workspace";
                            localProgressBarObj = updateProgressBar(app, localProgressBarObj, progressTitle, 90, 100);
        
                            % Saving Workspace
                            saveWorkspace(app, experiment)
                            progressTitle = experiment + " - Finished";
                            localProgressBarObj = updateProgressBar(app, localProgressBarObj, progressTitle, 100, 100);
                    end
                    Index = contains(pending, experiment);
                    pending(Index) = [];
                    ready{readyCont} = experiment;
                    readyCont = readyCont + 1;
                catch ex
                    displayLog(app, "=============================");
                    displayLog(app, "============ERROR============");
                    displayLog(app, "=============================");
                    displayLog(app, ex.message);
                    displayLog(app, getReport(ex));
                    disp(getReport(ex));
                    displayLog(app, "=============================");
                    displayLog(app, "=====" + experiment + " Skipped =====");
                    displayLog(app, "=============================");
                    saveLog(app)
                end
                
                updateProgressBar(app, localProgressBarObj, "", 0, 100);
                if e < length(app.experimentsFolders)
                    cla(localProgressBarObj.ax)
                    if isempty(ready)
                        plotExperimentsList(app, pending, "");
                    else
                        plotExperimentsList(app, pending, ready);
                    end
                else
                    updateProgressBar(app, localProgressBarObj, "Process Completed", 100, 100);
                    if isempty(pending)
                        plotExperimentsList(app, "", ready);
                    else
                        if isempty(ready)
                            plotExperimentsList(app, pending, "");
                        else
                            plotExperimentsList(app, pending, ready);
                        end
                    end
                end
                generalProgressBarObj = updateProgressBar(app, generalProgressBarObj, "Total Progress", e, length(app.experimentsFolders));
            end
            if ~isempty(pending)
                displayLog(app, "=============================");
                displayLog(app, "==Not Processed Experiments==");
                displayLog(app, "=============================");
                displayLog(app, pending);
            end
            displayLog(app, "---------------------------------");
            displayLog(app, "--------PROCESS FINISHED---------");
            displayLog(app, "---------------------------------");
            saveLog(app);
            app.Lamp.Color = [0.47,0.67,0.19];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'jet');
            app.UIFigure.Name = 'MATLAB App';
            app.ExperimentsloadPanel = uipanel(app.UIFigure);
            app.ExperimentsloadPanel.Title = 'Experiments load';
            app.SelectExperimentsButton = uibutton(app.ExperimentsloadPanel, 'push');
            app.SelectExperimentsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectExperimentsButtonPushed, true);
            app.SelectExperimentsButton.Text = 'Select Experiments';
            app.ExperimentsFolderEditFieldLabel = uilabel(app.ExperimentsloadPanel);
            app.ExperimentsFolderEditFieldLabel.Text = 'Experiments Folder';
            app.ExperimentsFolderEditField = uieditfield(app.ExperimentsloadPanel, 'text');
            app.StartProcessButton = uibutton(app.ExperimentsloadPanel, 'push');
            app.StartProcessButton.ButtonPushedFcn = createCallbackFcn(app, @StartProcessButtonPushed, true);
            app.StartProcessButton.Enable = 'off';
            app.StartProcessButton.Text = 'Start Process';
            app.DeconvolutionProgressPanel = uipanel(app.UIFigure);
            app.DeconvolutionProgressPanel.Title = 'Deconvolution Progress';
            app.DeconvolvedExperimentsTextAreaLabel = uilabel(app.DeconvolutionProgressPanel);
            app.DeconvolvedExperimentsTextAreaLabel.Text = 'Deconvolved Experiments';
            app.DeconvolvedExperimentsTextArea = uitextarea(app.DeconvolutionProgressPanel);
            app.localProgressBar = uipanel(app.DeconvolutionProgressPanel);
            app.generalProgressBar = uipanel(app.DeconvolutionProgressPanel);
            app.PendingExperimentsTextAreaLabel = uilabel(app.DeconvolutionProgressPanel);
            app.PendingExperimentsTextAreaLabel.Text = 'Pending Experiments';
            app.PendingExperimentsTextArea = uitextarea(app.DeconvolutionProgressPanel);
            app.Lamp = uilamp(app.DeconvolutionProgressPanel);
            app.LogPanel = uipanel(app.UIFigure);
            app.LogPanel.Title = 'Log';
            app.logTextarea = uitextarea(app.LogPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Batch_Deconv_Sniffer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end