classdef Deconv_Sniffer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DeconvSnifferV10UIFigure       matlab.ui.Figure
        VisualisationPanel             matlab.ui.container.Panel
        ShowHDImageButton              matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        OriginalImageTab               matlab.ui.container.Tab
        OriginalVisualisator           matlab.ui.control.UIAxes
        RegImagesShiftingTab           matlab.ui.container.Tab
        IntensitySlider                matlab.ui.control.Slider
        IntensitySliderLabel           matlab.ui.control.Label
        RegisteredVisualisator         matlab.ui.control.UIAxes
        BleachingCorrectionTab         matlab.ui.container.Tab
        BleachingVisualisator          matlab.ui.control.UIAxes
        DFImageTab                     matlab.ui.container.Tab
        DFVisualizator                 matlab.ui.control.UIAxes
        DFFImageTab                    matlab.ui.container.Tab
        DFFVisualizator                matlab.ui.control.UIAxes
        ResultsAnalysisPanel           matlab.ui.container.Panel
        LabelStep6                     matlab.ui.control.Label
        GOTORESULTSANALYSISAPPButton   matlab.ui.control.Button
        Step5ImageDeconvolutionPanel   matlab.ui.container.Panel
        LampStep5                      matlab.ui.control.Lamp
        DeconvolveStacksButton         matlab.ui.control.Button
        LabelStep5                     matlab.ui.control.Label
        Step4CalculateFStacksPanel     matlab.ui.container.Panel
        EndingSpinner                  matlab.ui.control.Spinner
        EndingSpinnerLabel             matlab.ui.control.Label
        StartingSpinner                matlab.ui.control.Spinner
        StartingSpinnerLabel           matlab.ui.control.Label
        GetFStackButton                matlab.ui.control.Button
        LampStep4                      matlab.ui.control.Lamp
        LabelStep4                     matlab.ui.control.Label
        Step3BleachingcorrectionPanel  matlab.ui.container.Panel
        UnusualBrigthnessInitFramesSpinner  matlab.ui.control.Spinner
        UnusualBrigthnessInitFramesSpinnerLabel  matlab.ui.control.Label
        LampStep3                      matlab.ui.control.Lamp
        CorrectedEditField             matlab.ui.control.EditField
        CorrectedEditFieldLabel        matlab.ui.control.Label
        CorrectBleachingButton         matlab.ui.control.Button
        LabelStep3                     matlab.ui.control.Label
        LogsconsolePanel               matlab.ui.container.Panel
        LogsTextArea                   matlab.ui.control.TextArea
        Step2ImageregistrationPanel    matlab.ui.container.Panel
        EditFieldMaxShift              matlab.ui.control.EditField
        MaxpixelsshiftedxyLabel        matlab.ui.control.Label
        LampStep2                      matlab.ui.control.Lamp
        LabelStep2                     matlab.ui.control.Label
        RegisterImagesButton           matlab.ui.control.Button
        Step1DefineExperimentNameandselectTIFFfilesPanel  matlab.ui.container.Panel
        ResetValuesButton              matlab.ui.control.Button
        LampStep1                      matlab.ui.control.Lamp
        ExperimentNameEditField        matlab.ui.control.EditField
        ExperimentNameEditFieldLabel   matlab.ui.control.Label
        SelectedFilesTextArea          matlab.ui.control.TextArea
        SelectedFilesTextAreaLabel     matlab.ui.control.Label
        SelectFilesButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        stacksNumber % Number of Experimental trials (stacks)
        imagesCellArray % Cell array containing all the tiff stacks matrix
        registeredImagesCellArray % Images alligned
        regCoeffs
        unbleachedImagesCellArray
        deltaFRegCellArray
        deltaFOverFRegCellArray
        baselinesRegCellArray
        deconvolvedStacks
        resultsAPP
        plotType
        imagesToVisualize
        UnbleachingResults
        DFImageToVisualise
        DFFImageToVisualise
        % Constants
        triggerTime
        RLIter
        RegistrationMode
    end
    
    methods (Access = private)
        
        function [regImagesCellArray, regCoeffs] = getRegisteredImages(app)
            %GETREGISTEREDIMAGES Summary of this function goes here
            displayLog(app, "Registering images... ")
            drawnow
            tic
            D = parallel.pool.DataQueue;
            f = uiprogressdlg(app.DeconvSnifferV10UIFigure, 'Title','Please Wait',...
                'Message', ' Registering Images... 0% (This may take several minutes)');
            afterEach(D, @nUpdateWaitbar);
            p = 1;
            
            [optimizer,metric] = imregconfig(app.RegistrationMode);

            fixed = mean(app.imagesCellArray{1}(:,:,4:17),3);
            [regImagesCellArray, regInfo] = deal(cell(app.stacksNumber,1));
            temp = app.imagesCellArray;
            parfor j = 1 : app.stacksNumber
                registered = zeros(size(temp{j}), 'single');
                reg = cell(1);
                for i = 1 : size(temp{j},3)
                    moving = temp{j}(:,:,i);
                    tform = imregtform(moving, fixed, 'translation', optimizer, metric);
                    registered(:,:,i) = imwarp(moving, tform, 'OutputView', imref2d(size(fixed)));
                    reg{i} = tform;
                end
                regImagesCellArray{j} = registered;
                regInfo{j} = reg;
                send(D, j); % Progress bar for parallel loops
            end
            regCoeffs = zeros(2, app.stacksNumber*size(temp{1},3));
            cont = 1;
            for i = 1 : app.stacksNumber
                for j = 1 : size(temp{1},3)
                    regCoeffs(1, cont) = regInfo{i}{j}.T(3,1);
                    regCoeffs(2, cont) = regInfo{i}{j}.T(3,2);
                    cont = cont + 1;
                end
            end

            % Calculate max. num. pixels moved
            [allRowZeros, allColZeros] = deal(max(abs(regCoeffs(1, :))), max(abs(regCoeffs(2, :))));
            app.EditFieldMaxShift.Value = "(" + round(allRowZeros,2) + "," + round(allColZeros,2) + ")";
            
            close(f)
            elapsedTime = toc;
            displayLog(app, "All Stacks are registered. Elapsed time > "+ string(elapsedTime)+ "Seconds")
            clc         
            
            function nUpdateWaitbar(app)
                f.Value = p/app.stacksNumber;
                f.Message = 'Registering Images... ('+string(fix(p*100/app.stacksNumber))+'%)'; % Progress Bar
                p = p + 1;
            end
        end
        
        function deconvolveStacks(app)
            tic
            displayLog(app, "Deconvolution Started... ")
            drawnow
            D = parallel.pool.DataQueue;
            f = uiprogressdlg(app.DeconvSnifferV10UIFigure, 'Title','Please Wait',...
                'Message', 'Deconvolving Stacks... 0% (This may take several minutes)');
            afterEach(D, @nUpdateWaitbar);
            p = 1;
            
            load("data/Triggered Kernel.mat", "defaultKernel")    
            kernel = defaultKernel;
            
            deconvStacks = cell(app.stacksNumber, 1);
            DFStacks = app.deltaFRegCellArray;
            iter = app.RLIter;
            parfor i = 1: app.stacksNumber
                deconvStacks{i, 1} = deconvlucy(DFStacks{i}, kernel, iter);
                send(D, i); % Progress bar for parallel loops
            end
            app.deconvolvedStacks = deconvStacks;
            
            close(f)
            elapsedTime = toc;
            displayLog(app, "Deconvolution Finished. Elapsed time > "+ string(elapsedTime)+ "Seconds")
            
            f = uiprogressdlg(app.DeconvSnifferV10UIFigure, 'Title','Please Wait',...
                'Message', 'Saving Workspace...', 'Indeterminate','on');
            regImagesCellArray = app.registeredImagesCellArray;
            regCoeffs = app.regCoeffs;
            experiment = string(app.ExperimentNameEditField.Value);
            imagesToVisualize = app.imagesToVisualize;
            if ~isempty(app.ExperimentNameEditField.Value)
                mkdir('data/' + experiment)
                workspace = 'data/'+ experiment +'/Results Workspace';
            else
                mkdir('data/New Experiment')
                workspace = 'data/New Experiment/Results Workspace';
            end
            myVars = {'experiment', 'deconvStacks', 'regImagesCellArray', 'regCoeffs', 'imagesToVisualize'};
            save(workspace, myVars{:}, '-V7.3');
            close(f)
            
            function nUpdateWaitbar(app)
                f.Value = p/app.stacksNumber;
                f.Message = 'Deconvolving stacks... ('+string(fix(p*100/app.stacksNumber))+'%) (This may take several minutes)';
                p = p + 1;
            end
        end
        
        function displayVisualisator(app)
            switch app.plotType
                case 'original'
                    cla(app.OriginalVisualisator, 'reset')
                    app.OriginalVisualisator.Position = [40,36,325,309];
                    if isempty(app.imagesToVisualize.Registered)
                        imshow(rescale(app.imagesToVisualize.Original, 0, 1),'Parent', app.OriginalVisualisator);
                    else
                        imshow(app.imagesToVisualize.Original,'Parent', app.OriginalVisualisator);
                    end
                    app.OriginalVisualisator.Title.String = "Original first image frame";
                case 'registered'
                    cla(app.RegisteredVisualisator, 'reset')
                    app.RegisteredVisualisator.Position = [13,45,398,331];
                    imshow(app.imagesToVisualize.Registered * app.IntensitySlider.Value ,'Parent', app.RegisteredVisualisator);
                    app.RegisteredVisualisator.Title.String = "Registered composite image";
                    app.IntensitySlider.Visible = true;
                    app.IntensitySliderLabel.Visible = true;
                case 'unbleached'
                    cla(app.BleachingVisualisator, 'reset')
                    app.UnbleachingResults.fits(:,1:app.UnusualBrigthnessInitFramesSpinner.Value) = NaN;
                    app.BleachingVisualisator.Position = [1,15,402,322];
                    bef = reshape(app.UnbleachingResults.avgBefore.', 1, []);
                    x = (1:numel(bef));
                    plot(app.BleachingVisualisator, x, bef)
                    hold(app.BleachingVisualisator, 'on')
                    aft = reshape(app.UnbleachingResults.avgAfter.', 1, []);
                    plot(app.BleachingVisualisator, x, aft)
                    whole = reshape(app.UnbleachingResults.avgWhole.', 1, []);
                    plot(app.BleachingVisualisator, x, whole)
                    fits = reshape(app.UnbleachingResults.fits.', 1, []);
                    plot(app.BleachingVisualisator, x, fits, '.')
                    numFrames = size(app.UnbleachingResults.avgWhole,2);
                    for i = 1 : app.stacksNumber
                        xline(app.BleachingVisualisator, i*numFrames,'--')
                    end
                    app.BleachingVisualisator.XGrid = 'on';
                    app.BleachingVisualisator.YGrid = 'on';
                    app.BleachingVisualisator.XMinorGrid = 'on';
                    app.BleachingVisualisator.YMinorGrid = 'on';
                    app.BleachingVisualisator.Title.String = "Average per image Before and After bleaching correction";
                    app.BleachingVisualisator.Subtitle.String = "All stacks";
                    legend(app.BleachingVisualisator, 'Mean Brightness Before correction', 'Mean Brightness After in-trial correctione',...
                        'Mean Brightness After across-trial correctione','Fittings','Trials','Location', 'southoutside', 'Interpreter', 'none' );
                    ylabel(app.BleachingVisualisator, 'Brightness');
                    xlabel(app.BleachingVisualisator, 'Frames of all stacks')
                    M = max(reshape(app.UnbleachingResults.avgBefore.', 1, []));
                    m = min(reshape(app.UnbleachingResults.avgAfter.', 1, []));
                    ylim(app.BleachingVisualisator, [m-(m/20) M+(M/20)])
                    xlim(app.BleachingVisualisator, [1 length(reshape(app.UnbleachingResults.avgAfter.', 1, []))])
                case 'DF'
                    cla(app.DFVisualizator, 'reset')
                    app.DFVisualizator.Position = [40,36,325,309];
                    imshow(mat2gray(app.DFImageToVisualise),'Parent', app.DFVisualizator);
                    app.DFVisualizator.Title.String = "DF Images Average";
                case 'DFF'
                    cla(app.DFFVisualizator, 'reset')
                    app.DFFVisualizator.Position = [40,36,325,309];
                    imshow(mat2gray(app.DFFImageToVisualise),'Parent', app.DFFVisualizator);
                    app.DFFVisualizator.Title.String = "DFF Images Average";
            end
        end
        
        function displayLog(app, newString)
            message = '[' + string(datetime('now')) + ']: ' + newString;
            
            if strcmp(app.LogsTextArea.Value,'')
                message_disp = message;
            else
                message_disp = [app.LogsTextArea.Value(:)', message];
            end
            app.LogsTextArea.Value = message_disp;
            app.DeconvSnifferV10UIFigure.Visible = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            paramsMap = getParametersMap();
            app.RLIter = paramsMap('RLIterations');
            app.triggerTime = paramsMap('triggerTime');
            regMode = paramsMap('regMode');
            if regMode == 0
                app.RegistrationMode = 'monomodal';
            else
                app.RegistrationMode = 'multimodal';
            end
            app.imagesToVisualize.Original = [];
            app.imagesToVisualize.Registered = [];
        end

        % Button pushed function: SelectFilesButton
        function SelectFilesButtonPushed(app, event)
            % ---------------------- Imput data to deconvolve ---------------------- %
            % Multiple files selection avaliable.
            % Only Tiff files allowed
            [StackFiles, StackFilesURL] = uigetfile({'*.tif','tif Files (*.tif)';...
                '*.TIF','TIF Files (*.TIF)'}, 'Please Select a TIF Images File',...
                'MultiSelect', 'on');
            app.DeconvSnifferV10UIFigure.Visible = 'off';
            app.DeconvSnifferV10UIFigure.Visible = 'on';
            
            % Check that at least 1 file is selected
            if isequal(StackFiles, 0)
                warndlg('No files selected','Please select a Tiff file');
                displayLog(app, "No files selected... Closing")
                return
            end
            
            % Check how many files were selected
            if iscell(StackFiles)
                app.stacksNumber = size(StackFiles,2);
            else
                app.stacksNumber = 1;
            end
            
            app.SelectedFilesTextArea.Value = StackFiles;
            
            % ---------------------- Load stacks to cell array --------------------- %
            app.imagesCellArray = cell(app.stacksNumber, 1); % Cell array containing all the 3D stacks loaded
            displayLog(app, "Loading images files... ")
            drawnow
            tic
            f = uiprogressdlg(app.DeconvSnifferV10UIFigure, 'Title','Please Wait',...
                'Message', ' Loading Images... 0%');
            for i = 1: app.stacksNumber
                if app.stacksNumber > 1
                    imageMatrixCell = getMatrixFromImages(string(StackFiles(i)),...
                        string(StackFilesURL), false);
                else
                    imageMatrixCell = getMatrixFromImages(string(StackFiles),...
                        string(StackFilesURL), false);
                end
                app.imagesCellArray{i, 1} = cat(3,imageMatrixCell{:});
                f.Value = i/app.stacksNumber;
                f.Message = ' Loading Images... ('+string(fix(i*100/app.stacksNumber))+'%)'; % Progress Bar
            end
            close(f)
            elapsedTime = toc;
            displayLog(app, "All Images loaded in memory. Elapsed time > "+ string(elapsedTime)+ " Seconds");
            
            % Extract Experiment Name
            if ispc
                urlSplit = regexp(StackFilesURL,'\','split');
                app.ExperimentNameEditField.Value = string(urlSplit(end-1));
            elseif isunix || ismac
                urlSplit = regexp(StackFilesURL,'/','split');
                app.ExperimentNameEditField.Value = string(urlSplit(end-1));
            end
            
            % Plot image in visualisator
            app.TabGroup.SelectedTab = app.OriginalImageTab;
            app.OriginalVisualisator.Visible = true;
            app.imagesToVisualize.Original = getShiftedCompositeImage(app.imagesCellArray);
            app.imagesToVisualize.Template = mean(app.imagesCellArray{1}(:,:,app.UnusualBrigthnessInitFramesSpinner.Value:app.triggerTime), 3);
            app.plotType = 'original';
            displayVisualisator(app)
            
            app.LampStep1.Color = [0 1 0];
            app.RegisterImagesButton.Enable = true;
            app.LampStep2.Color = [1 0 0];
            app.CorrectBleachingButton.Enable = false;
            app.LampStep3.Color = [1 0 0];
            app.GetFStackButton.Enable = false;
            app.LampStep4.Color = [1 0 0];
            app.DeconvolveStacksButton.Enable = false;
            app.LampStep5.Color = [1 0 0];
            app.GOTORESULTSANALYSISAPPButton.Enable = false;
            app.EditFieldMaxShift.Value = "";
            
        end

        % Value changed function: IntensitySlider
        function IntensitySliderValueChanged(app, event)
            app.IntensitySlider.Value = round(app.IntensitySlider.Value,1);
            displayVisualisator(app)
        end

        % Button pushed function: RegisterImagesButton
        function RegisterImagesButtonPushed(app, event)
            [app.registeredImagesCellArray, app.regCoeffs] = getRegisteredImages(app);
            app.imagesToVisualize.Registered = getShiftedCompositeImage(app.registeredImagesCellArray);
            app.imagesToVisualize = correctRGBBrightness(app.imagesToVisualize, app.stacksNumber);
            app.TabGroup.SelectedTab = app.RegImagesShiftingTab;
            app.plotType = 'original';
            displayVisualisator(app)
            app.plotType = 'registered';
            displayVisualisator(app)
            app.LampStep2.Color = [0 1 0];
            app.CorrectBleachingButton.Enable = true;
            app.LampStep3.Color = [1 0 0];
            app.GetFStackButton.Enable = false;
            app.LampStep4.Color = [1 0 0];
            app.DeconvolveStacksButton.Enable = false;
            app.LampStep5.Color = [1 0 0];
            app.GOTORESULTSANALYSISAPPButton.Enable = false;
            app.CorrectedEditField.Value = "";
        end

        % Button pushed function: CorrectBleachingButton
        function CorrectBleachingButtonPushed(app, event)
            displayLog(app, "Correcting Bleaching... ")
            drawnow
            tic
            f = uiprogressdlg(app.DeconvSnifferV10UIFigure, 'Title','Please Wait',...
                'Message', 'Correcting Bleaching...', 'Indeterminate','on');
            [app.unbleachedImagesCellArray, meanPercentDiff, ...
            allAvgBefore, allAvgAfterItrial, allAvgAfterWhole, allFits, allCoeffs]...
            = getImagesWithoutBleaching(app.registeredImagesCellArray,...
               app.UnusualBrigthnessInitFramesSpinner.Value, app.triggerTime);
            
            app.UnbleachingResults.avgBefore = allAvgBefore;
            app.UnbleachingResults.avgAfter = allAvgAfterItrial;
            app.UnbleachingResults.avgWhole = allAvgAfterWhole;
            app.UnbleachingResults.fits = allFits;
            app.UnbleachingResults.allCoeffs = allCoeffs;
            app.UnbleachingResults.meanPercentDiff = meanPercentDiff;
            app.CorrectedEditField.Value = string(meanPercentDiff);

            close(f)
            elapsedTime = toc;
            app.TabGroup.SelectedTab = app.BleachingCorrectionTab;
            app.plotType = 'unbleached';
            displayVisualisator(app)
            displayLog(app, "Bleaching corrected. Elapsed time > "+ string(elapsedTime)+ " Seconds");
            app.LampStep3.Color = [0 1 0];
            app.GetFStackButton.Enable = true;
            app.LampStep4.Color = [1 0 0];
            app.DeconvolveStacksButton.Enable = false;
            app.LampStep5.Color = [1 0 0];
            app.GOTORESULTSANALYSISAPPButton.Enable = false;
        end

        % Button pushed function: GetFStackButton
        function GetFStackButtonPushed(app, event)
            app.deltaFRegCellArray = cell(app.stacksNumber, 1);
            app.baselinesRegCellArray = cell(app.stacksNumber, 1);
            app.deltaFOverFRegCellArray = cell(app.stacksNumber, 1);
            displayLog(app, "Getting ΔF Images... ")
            tic
            f = uiprogressdlg(app.DeconvSnifferV10UIFigure, 'Title','Please Wait',...
                'Message', 'Getting ΔF Images... 0%');
            for i = 1: app.stacksNumber
                [app.deltaFRegCellArray{i, 1}, app.deltaFOverFRegCellArray{i, 1}, app.baselinesRegCellArray{i, 1}] =...
                    getDFMatrix(0, app.unbleachedImagesCellArray{i}, app.StartingSpinner.Value, app.EndingSpinner.Value);
                f.Value = i/app.stacksNumber;
                f.Message = 'Getting ΔF Images... ('+string(fix(i*100/app.stacksNumber))+'%)'; % Progress Bar
            end
            close(f)
            elapsedTime = toc;
            displayLog(app, "ΔF Images generated. Elapsed time > "+ string(elapsedTime)+ " Seconds");

            app.TabGroup.SelectedTab = app.DFImageTab;
            app.DFImageToVisualise = max(cat(3,app.deltaFRegCellArray{:}), [], 3);
            app.DFFImageToVisualise  = max(cat(3,app.deltaFOverFRegCellArray{:}), [], 3);
            app.plotType = 'DF';
            displayVisualisator(app)
            app.plotType = 'DFF';
            displayVisualisator(app)

            app.LampStep4.Color = [0 1 0];
            app.DeconvolveStacksButton.Enable = true;
            app.LampStep5.Color = [1 0 0];
            app.GOTORESULTSANALYSISAPPButton.Enable = false;            
        end

        % Button pushed function: DeconvolveStacksButton
        function DeconvolveStacksButtonPushed(app, event)
            deconvolveStacks(app)
            app.LampStep5.Color = [0 1 0];
            app.GOTORESULTSANALYSISAPPButton.Enable = true;
        end

        % Button pushed function: GOTORESULTSANALYSISAPPButton
        function GOTORESULTSANALYSISAPPButtonPushed(app, event)
            if ~isempty(app.ExperimentNameEditField.Value)
                app.resultsAPP = Results_Analyzis({app.ExperimentNameEditField.Value, ...
                    app.imagesToVisualize, ...
                    app.UnusualBrigthnessInitFramesSpinner.Value, ...
                    app.StartingSpinner.Value, app.EndingSpinner.Value, ...
                    app.registeredImagesCellArray, app.regCoeffs, ...
                    app.deconvolvedStacks});
            else
                warndlg('Please enter an Experiment Name','Warning');
            end
        end

        % Button pushed function: ResetValuesButton
        function ResetValuesButtonPushed(app, event)
            app.stacksNumber = [];
            app.imagesCellArray = [];
            app.registeredImagesCellArray = [];
            app.deltaFRegCellArray = [];
            app.baselinesRegCellArray = [];
            app.deconvolvedStacks = [];
            app.resultsAPP = [];
            
            app.LampStep1.Color = [1 0 0];
            app.LampStep2.Color = [1 0 0];
            app.LampStep3.Color = [1 0 0];
            app.LampStep4.Color = [1 0 0];
            app.LampStep5.Color = [1 0 0];
            
            app.RegisterImagesButton.Enable = false;
            app.CorrectBleachingButton.Enable = false;
            app.GetFStackButton.Enable = false;
            app.DeconvolveStacksButton.Enable = false;
            app.GOTORESULTSANALYSISAPPButton.Enable = false;
            
            cla(app.OriginalVisualisator,"reset");
            app.OriginalVisualisator.Visible = false;
            cla(app.RegisteredVisualisator,"reset");
            app.RegisteredVisualisator.Visible = false;
            app.IntensitySlider.Visible = false;
            app.IntensitySliderLabel.Visible = false;
            cla(app.BleachingVisualisator,"reset");
            app.BleachingVisualisator.Visible = false;
                        
            app.ExperimentNameEditField.Value = "";
            app.SelectedFilesTextArea.Value = "";
            app.LogsTextArea.Value = "";
            app.EditFieldMaxShift.Value = "";
            app.CorrectedEditField.Value = "";
        end

        % Close request function: DeconvSnifferV10UIFigure
        function DeconvSnifferV10UIFigureCloseRequest(app, event)
            clear clearvars
            delete(app)
        end

        % Button pushed function: ShowHDImageButton
        function ShowHDImageButtonPushed(app, event)
            keySet = {'Original Image','Reg. Images Shifting','Bleaching Correction','DF Image','DF/F Image'};
            valueSet = [1 2 3 4 5];
            M = containers.Map(keySet,valueSet);

%             tabGroupAxes = findobj(get(gcf,'children'),'type','Axes');
            tabGroupAxes = findobj(app.TabGroup);
            fig = figure(); clf;
            ind = M(app.TabGroup.SelectedTab.Title);
            if ind ~= 2
                copyobj(tabGroupAxes(ind + 1).Children, fig)
            else
                copyobj(tabGroupAxes(ind + 1).Children(3), fig)
            end
%             copyobj(tabGroupAxes(ind), fig)
            set(gca,'ActivePositionProperty','outerposition')
            set(gca,'Units','normalized')
            set(gca,'OuterPosition',[0 0 1 1])
            set(gca,'position',[0.1300 0.1100 0.7750 0.8150])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DeconvSnifferV10UIFigure = uifigure('Visible', 'off');
            app.DeconvSnifferV10UIFigure.Name = 'Deconv - Sniffer V. 1.0';
            app.DeconvSnifferV10UIFigure.CloseRequestFcn = createCallbackFcn(app, @DeconvSnifferV10UIFigureCloseRequest, true);
            app.Step1DefineExperimentNameandselectTIFFfilesPanel = uipanel(app.DeconvSnifferV10UIFigure);
            app.Step1DefineExperimentNameandselectTIFFfilesPanel.Title = 'Step 1: Define Experiment Name and select TIFF files';
            app.SelectFilesButton = uibutton(app.Step1DefineExperimentNameandselectTIFFfilesPanel, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesButtonPushed, true);
            app.SelectFilesButton.Text = 'Select Files';
            app.SelectedFilesTextAreaLabel = uilabel(app.Step1DefineExperimentNameandselectTIFFfilesPanel);
            app.SelectedFilesTextAreaLabel.Text = 'Selected Files';
            app.SelectedFilesTextArea = uitextarea(app.Step1DefineExperimentNameandselectTIFFfilesPanel);
            app.SelectedFilesTextArea.Editable = 'off';
            app.SelectedFilesTextArea.Value = {'No files selected yet.'};
            app.ExperimentNameEditFieldLabel = uilabel(app.Step1DefineExperimentNameandselectTIFFfilesPanel);
            app.ExperimentNameEditFieldLabel.Text = 'Experiment Name';
            app.ExperimentNameEditField = uieditfield(app.Step1DefineExperimentNameandselectTIFFfilesPanel, 'text');
            app.LampStep1 = uilamp(app.Step1DefineExperimentNameandselectTIFFfilesPanel);
            app.ResetValuesButton = uibutton(app.Step1DefineExperimentNameandselectTIFFfilesPanel, 'push');
            app.ResetValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetValuesButtonPushed, true);
            app.ResetValuesButton.Text = 'Reset Values';
            app.Step2ImageregistrationPanel = uipanel(app.DeconvSnifferV10UIFigure);
            app.Step2ImageregistrationPanel.Title = 'Step 2: Image registration';
            app.RegisterImagesButton = uibutton(app.Step2ImageregistrationPanel, 'push');
            app.RegisterImagesButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterImagesButtonPushed, true);
            app.RegisterImagesButton.Enable = 'off';
            app.RegisterImagesButton.Text = 'Register Images';
            app.LabelStep2 = uilabel(app.Step2ImageregistrationPanel);
            app.LabelStep2.Text = 'Horizontal and vertical allignment is performed to avoid signals displacement over time. ';
            app.LampStep2 = uilamp(app.Step2ImageregistrationPanel);
            app.MaxpixelsshiftedxyLabel = uilabel(app.Step2ImageregistrationPanel);
            app.MaxpixelsshiftedxyLabel.Text = 'Max. pixels shifted (X, Y)';
            app.EditFieldMaxShift = uieditfield(app.Step2ImageregistrationPanel, 'text');
            app.EditFieldMaxShift.Editable = 'off';
            app.LogsconsolePanel = uipanel(app.DeconvSnifferV10UIFigure);
            app.LogsconsolePanel.Title = 'Logs console';
            app.LogsTextArea = uitextarea(app.LogsconsolePanel);
            app.Step3BleachingcorrectionPanel = uipanel(app.DeconvSnifferV10UIFigure);
            app.Step3BleachingcorrectionPanel.Title = 'Step 3: Bleaching correction';
            app.LabelStep3 = uilabel(app.Step3BleachingcorrectionPanel);
            app.LabelStep3.Text = 'Bleaching correction is made by fitting the averaged brigthness decay per trial with a double exponential function. Each trial stack is normalized according to this decay, and a general correction is made based on the first trial amplitude.';
            app.CorrectBleachingButton = uibutton(app.Step3BleachingcorrectionPanel, 'push');
            app.CorrectBleachingButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectBleachingButtonPushed, true);
            app.CorrectBleachingButton.Enable = 'off';
            app.CorrectBleachingButton.Text = 'Correct Bleaching';
            app.CorrectedEditFieldLabel = uilabel(app.Step3BleachingcorrectionPanel);
            app.CorrectedEditFieldLabel.Text = '% Corrected:';
            app.CorrectedEditField = uieditfield(app.Step3BleachingcorrectionPanel, 'text');
            app.CorrectedEditField.Editable = 'off';
            app.LampStep3 = uilamp(app.Step3BleachingcorrectionPanel);
            app.UnusualBrigthnessInitFramesSpinnerLabel = uilabel(app.Step3BleachingcorrectionPanel);
            app.UnusualBrigthnessInitFramesSpinnerLabel.Text = 'Unusual Brigthness Init. Frames';
            app.UnusualBrigthnessInitFramesSpinner = uispinner(app.Step3BleachingcorrectionPanel);
            app.UnusualBrigthnessInitFramesSpinner.Limits = [1 Inf];
            app.UnusualBrigthnessInitFramesSpinner.Value = 4;
            app.Step4CalculateFStacksPanel = uipanel(app.DeconvSnifferV10UIFigure);
            app.Step4CalculateFStacksPanel.Title = 'Step 4: Calculate ΔF Stacks';
            app.LabelStep4 = uilabel(app.Step4CalculateFStacksPanel);
            app.LabelStep4.Text = 'Dendritic branches are substracted from the images to isolete the signals. Please select the frames where the branches are constant and are no signals are triggered.';
            app.LampStep4 = uilamp(app.Step4CalculateFStacksPanel);
            app.GetFStackButton = uibutton(app.Step4CalculateFStacksPanel, 'push');
            app.GetFStackButton.ButtonPushedFcn = createCallbackFcn(app, @GetFStackButtonPushed, true);
            app.GetFStackButton.Enable = 'off';
            app.GetFStackButton.Text = 'Get ΔF Stack';
            app.StartingSpinnerLabel = uilabel(app.Step4CalculateFStacksPanel);
            app.StartingSpinnerLabel.Text = 'Starting ';
            app.StartingSpinner = uispinner(app.Step4CalculateFStacksPanel);
            app.StartingSpinner.Limits = [1 Inf];
            app.StartingSpinner.Value = 4;
            app.EndingSpinnerLabel = uilabel(app.Step4CalculateFStacksPanel);
            app.EndingSpinnerLabel.Text = 'Ending';
            app.EndingSpinner = uispinner(app.Step4CalculateFStacksPanel);
            app.EndingSpinner.Limits = [1 Inf];
            app.EndingSpinner.Value = 17;
            app.Step5ImageDeconvolutionPanel = uipanel(app.DeconvSnifferV10UIFigure);
            app.Step5ImageDeconvolutionPanel.Title = 'Step 5: Image Deconvolution';
            app.LabelStep5 = uilabel(app.Step5ImageDeconvolutionPanel);
            app.LabelStep5.Text = 'The Richardson-Lucy algorithm runs over the image stacks to isolate synapse signals determining space and temporal occurrence and signals intensities.';
            app.DeconvolveStacksButton = uibutton(app.Step5ImageDeconvolutionPanel, 'push');
            app.DeconvolveStacksButton.ButtonPushedFcn = createCallbackFcn(app, @DeconvolveStacksButtonPushed, true);
            app.DeconvolveStacksButton.Enable = 'off';
            app.DeconvolveStacksButton.Text = 'Deconvolve Stacks';
            app.LampStep5 = uilamp(app.Step5ImageDeconvolutionPanel);
            app.ResultsAnalysisPanel = uipanel(app.DeconvSnifferV10UIFigure);
            app.ResultsAnalysisPanel.Title = 'Results Analysis';
            app.GOTORESULTSANALYSISAPPButton = uibutton(app.ResultsAnalysisPanel, 'push');
            app.GOTORESULTSANALYSISAPPButton.ButtonPushedFcn = createCallbackFcn(app, @GOTORESULTSANALYSISAPPButtonPushed, true);
            app.GOTORESULTSANALYSISAPPButton.Text = 'GO TO RESULTS ANALYSIS APP';
            app.LabelStep6 = uilabel(app.ResultsAnalysisPanel);
            app.LabelStep6.Text = 'Regions of interest and their traces information are exported using the deconvolution results. Also the ROIs can be evaluated interactively in the results analysis app.';
            app.VisualisationPanel = uipanel(app.DeconvSnifferV10UIFigure);
            app.VisualisationPanel.Title = 'Visualisation Panel';
            app.TabGroup = uitabgroup(app.VisualisationPanel);
            app.OriginalImageTab = uitab(app.TabGroup);
            app.OriginalImageTab.Title = 'Original Image';
            app.OriginalVisualisator = uiaxes(app.OriginalImageTab);
            app.OriginalVisualisator.Visible = 'off';
            app.RegImagesShiftingTab = uitab(app.TabGroup);
            app.RegImagesShiftingTab.Title = 'Reg. Images Shifting';
            app.RegisteredVisualisator = uiaxes(app.RegImagesShiftingTab);
            app.RegisteredVisualisator.Visible = 'off';
            app.IntensitySliderLabel = uilabel(app.RegImagesShiftingTab);
            app.IntensitySliderLabel.Visible = 'off';
            app.IntensitySliderLabel.Text = 'Intensity';
            app.IntensitySlider = uislider(app.RegImagesShiftingTab);
            app.IntensitySlider.Limits = [1 3];
            app.IntensitySlider.MajorTicks = [1 1.5 2 2.5 3];
            app.IntensitySlider.MajorTickLabels = {'1', '1.5', '2', '2.5', '3'};
            app.IntensitySlider.ValueChangedFcn = createCallbackFcn(app, @IntensitySliderValueChanged, true);
            app.IntensitySlider.MinorTicks = [1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3];
            app.IntensitySlider.Visible = 'off';
            app.IntensitySlider.Value = 1;
            app.BleachingCorrectionTab = uitab(app.TabGroup);
            app.BleachingCorrectionTab.Title = 'Bleaching Correction';
            app.BleachingVisualisator = uiaxes(app.BleachingCorrectionTab);
            app.BleachingVisualisator.Visible = 'off';
            app.DFImageTab = uitab(app.TabGroup);
            app.DFImageTab.Title = 'DF Image';
            app.DFVisualizator = uiaxes(app.DFImageTab);
            app.DFVisualizator.Visible = 'off';
            app.DFFImageTab = uitab(app.TabGroup);
            app.DFFImageTab.Title = 'DF/F Image';
            app.DFFVisualizator = uiaxes(app.DFFImageTab);
            app.DFFVisualizator.Visible = 'off';
            app.ShowHDImageButton = uibutton(app.VisualisationPanel, 'push');
            app.ShowHDImageButton.ButtonPushedFcn = createCallbackFcn(app, @ShowHDImageButtonPushed, true);
            app.ShowHDImageButton.Text = 'Show HD Image';
            % Show the figure after all components are created
            app.DeconvSnifferV10UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Deconv_Sniffer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DeconvSnifferV10UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DeconvSnifferV10UIFigure)
        end
    end
end