classdef Deconv_Sniffer_SP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DeconvSnifferV10UIFigure      matlab.ui.Figure
        ResultsAnalysisPanel          matlab.ui.container.Panel
        LabelStep6                    matlab.ui.control.Label
        GOTORESULTSANALYSISAPPButton  matlab.ui.control.Button
        LogsconsolePanel              matlab.ui.container.Panel
        LogsTextArea                  matlab.ui.control.TextArea
        Step5ImageDeconvolutionPanel  matlab.ui.container.Panel
        LampStep4                     matlab.ui.control.Lamp
        DeconvolveStacksButton        matlab.ui.control.Button
        LabelStep4                    matlab.ui.control.Label
        Step3CalculateFStacksPanel    matlab.ui.container.Panel
        WindowLengthSpinner           matlab.ui.control.Spinner
        LengthLabel                   matlab.ui.control.Label
        WindowOffsetSpinner           matlab.ui.control.Spinner
        OffsetLabel                   matlab.ui.control.Label
        GetFStackButton               matlab.ui.control.Button
        LampStep3                     matlab.ui.control.Lamp
        LabelStep3                    matlab.ui.control.Label
        Step2ImageregistrationandinfocuscheckPanel  matlab.ui.container.Panel
        EditFieldMaxShift             matlab.ui.control.EditField
        MaxpixelsshiftedxyLabel       matlab.ui.control.Label
        LampStep2                     matlab.ui.control.Lamp
        LabelStep2                    matlab.ui.control.Label
        RegisterImagesButton          matlab.ui.control.Button
        VisualisationPanel            matlab.ui.container.Panel
        ShowHDImageButton             matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        OriginalImagesTab             matlab.ui.container.Tab
        OriginalVisualisator          matlab.ui.control.UIAxes
        RegImagesTab                  matlab.ui.container.Tab
        IntensitySlider               matlab.ui.control.Slider
        IntensitySliderLabel          matlab.ui.control.Label
        RegisteredVisualisator        matlab.ui.control.UIAxes
        OutofFocusCheckTab            matlab.ui.container.Tab
        UnFocusPanel                  matlab.ui.container.Panel
        DFImageTab                    matlab.ui.container.Tab
        DFVisualizator                matlab.ui.control.UIAxes
        Step1DefineExperimentNameandselectTIFFfilesPanel  matlab.ui.container.Panel
        ResetValuesButton             matlab.ui.control.Button
        LampStep1                     matlab.ui.control.Lamp
        ExperimentNameEditField       matlab.ui.control.EditField
        ExperimentNameEditFieldLabel  matlab.ui.control.Label
        SelectedFilesTextArea         matlab.ui.control.TextArea
        SelectedFilesTextAreaLabel    matlab.ui.control.Label
        SelectFilesButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        RLIter
        triggerTime
        RegistrationMode
        numStacks
        stacksNumber
        imagesCell
        registeredImages
        stdFactor
        unFocusResults
        DFImage
        OriginalImageToVisualise
        RegisteredImageToVisualise
        DFImageToVisualise
        plotType
    end
    
    methods (Access = private)

        function splitImageCell = splitImages(app, images)
            [m, n, z] = size(images);
            D = (z / app.numStacks) * ones(1,app.numStacks);
            splitImageCell = mat2cell(images, m, n, D);
            splitImageCell = reshape(splitImageCell, [], app.numStacks);
        end

        function [regImagesCellArray, regCoeffs] = getRegisteredImages(app, splitImageCell)
            %GETREGISTEREDIMAGES Summary of this function goes here
            displayLog(app, "Registering images... ")
            drawnow
            tic
            D = parallel.pool.DataQueue;
            f = uiprogressdlg(app.DeconvSnifferV10UIFigure, 'Title','Please Wait',...
                'Message', ' Registering Images... 0% (This may take several minutes)');
            afterEach(D, @nUpdateWaitbar);
            p = 1;

            [optimizer,metric] = imregconfig(app.RegistrationMode);
            
            fixed = mean(splitImageCell{1}(:,:,4:17),3);
            [regImagesCellArray, regInfo] = deal(cell(app.stacksNumber,1));
            temp = splitImageCell;
            parfor j = 1 : app.stacksNumber
                registered = zeros(size(temp{j}), 'single');
                reg = cell(1);
                for i = 1 : size(temp{j},3)
                    moving = temp{j}(:,:,i);
                    tform = imregtform(moving, fixed, 'translation', optimizer, metric);
                    registered(:,:,i) = imwarp(moving, tform, 'OutputView', imref2d(size(fixed)));
                    reg{i} = tform;
                end
                regImagesCellArray{j} = registered;
                regInfo{j} = reg;
                send(D, j); % Progress bar for parallel loops
            end
            regCoeffs = zeros(2, app.stacksNumber*size(temp{1},3));
            cont = 1;
            for i = 1 : app.stacksNumber
                for j = 1 : size(temp{1},3)
                    regCoeffs(1, cont) = regInfo{i}{j}.T(3,1);
                    regCoeffs(2, cont) = regInfo{i}{j}.T(3,2);
                    cont = cont + 1;
                end
            end
            
            % Calculate max. num. pixels moved
            [allRowZeros, allColZeros] = deal(max(abs(regCoeffs(1, :))), max(abs(regCoeffs(2, :))));
            app.EditFieldMaxShift.Value = "(" + round(allRowZeros,2) + "," + round(allColZeros,2) + ")";

            close(f)
            elapsedTime = toc;
            displayLog(app, "All Stacks are registered. Elapsed time > "+ string(elapsedTime)+ "Seconds")
            clc
            
            function nUpdateWaitbar(app)
                f.Value = p/app.stacksNumber;
                f.Message = 'Registering Images... ('+string(fix(p*100/app.stacksNumber))+'%)'; % Progress Bar
                p = p + 1;
            end
        end

        function displayVisualisator(app)
            switch app.plotType
                case 'original'
                    cla(app.OriginalVisualisator, 'reset')
                    app.OriginalVisualisator.Position = [40,36,325,309];
                    imshow(rescale(app.OriginalImageToVisualise, 0, 1),'Parent', app.OriginalVisualisator);
                    app.OriginalVisualisator.Title.String = "Original first image frame";
                case 'registered'
                    cla(app.RegisteredVisualisator, 'reset')
                    app.RegisteredVisualisator.Position = [13,45,398,331];
                    imshow(rescale(app.RegisteredImageToVisualise, 0, app.IntensitySlider.Value),'Parent', app.RegisteredVisualisator);
                    app.RegisteredVisualisator.Title.String = "Registered composite image";
                    app.IntensitySlider.Visible = true;
                    app.IntensitySliderLabel.Visible = true;
                case 'unFocus'
                    cla(app.UnFocusPanel, 'reset')
                    app.UnFocusPanel.AutoResizeChildren = 'off';
                    ax1 = subplot(2,1,1,'Parent',app.UnFocusPanel);
                    plot(ax1, app.unFocusResults.meanStack)
                    ax2 = subplot(2,1,2,'Parent',app.UnFocusPanel);
                    plot(ax2, app.unFocusResults.SQRTSSEnormalized)
                    hold(ax2, "on")
                    plot(ax2, app.unFocusResults.locsPos, app.unFocusResults.pksPos, 'or')
                    plot(ax2, app.unFocusResults.locsNeg, -app.unFocusResults.pksNeg, 'or')
                case 'DF'
                    cla(app.DFVisualizator, 'reset')
                    app.DFVisualizator.Position = [40,36,325,309];
                    imshow(mat2gray(app.DFImageToVisualise),'Parent', app.DFVisualizator);
                    app.DFVisualizator.Title.String = "DF Images Average";
            end
        end
        
        function displayLog(app, newString)
            message = '[' + string(datetime('now')) + ']: ' + newString;
            
            if strcmp(app.LogsTextArea.Value,'')
                message_disp = message;
            else
                message_disp = [app.LogsTextArea.Value(:)', message];
            end
            app.LogsTextArea.Value = message_disp;
            app.DeconvSnifferV10UIFigure.Visible = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            paramsMap = getParametersMap();
            app.RLIter = paramsMap('RLIterations');
            app.triggerTime = paramsMap('triggerTime');
            app.stdFactor = paramsMap('stdFactor');
            regMode = paramsMap('regMode');
            if regMode == 0
                app.RegistrationMode = 'monomodal';
            else
                app.RegistrationMode = 'multimodal';
            end
            app.numStacks = paramsMap('numStacks');
            app.WindowOffsetSpinner.Value = double(paramsMap('windowOffset'));
            app.WindowLengthSpinner.Value = double(paramsMap('windowLength'));

            load("MarieProcessedStacks.mat", "regImages")
            app.registeredImages = regImages{1};
        end

        % Button pushed function: SelectFilesButton
        function SelectFilesButtonPushed(app, event)
%             % ---------------------- Imput data to deconvolve ---------------------- %
%             % Multiple files selection avaliable.
%             % Only Tiff files allowed
%             [StackFiles, StackFilesURL] = uigetfile({'*.tif','tif Files (*.tif)';...
%                 '*.TIF','TIF Files (*.TIF)'}, 'Please Select a TIF Images File');
%             app.DeconvSnifferV10UIFigure.Visible = 'off';
%             app.DeconvSnifferV10UIFigure.Visible = 'on';
%             
%             % Check that at least 1 file is selected
%             if isequal(StackFiles, 0)
%                 warndlg('No files selected','Please select a Tiff file');
%                 displayLog(app, "No files selected... Closing")
%                 return
%             end
%             
%             app.stacksNumber = 1;
%             app.SelectedFilesTextArea.Value = StackFiles;
%             
%             % ---------------------- Load stacks to cell array --------------------- %
%             app.imagesCell = cell(app.stacksNumber, 1); % Cell array containing all the 3D stacks loaded
%             displayLog(app, "Loading images files... ")
%             drawnow
%             tic
%             f = uiprogressdlg(app.DeconvSnifferV10UIFigure, 'Title','Please Wait',...
%                 'Message', ' Loading Images...', 'Indeterminate', 'on');
%             imageMatrixCell = getMatrixFromImages(string(StackFiles),...
%                 string(StackFilesURL), false);
%             app.imagesCell = cat(3,imageMatrixCell{:});
% 
%             close(f)
%             elapsedTime = toc;
%             displayLog(app, "All Images loaded in memory. Elapsed time > "+ string(elapsedTime)+ " Seconds");
%             
% 
% 
%             % Extract Experiment Name
%             if ispc
%                 urlSplit = regexp(StackFilesURL,'\','split');
%                 app.ExperimentNameEditField.Value = string(urlSplit(end-1));
%             elseif isunix || ismac
%                 urlSplit = regexp(StackFilesURL,'/','split');
%                 app.ExperimentNameEditField.Value = string(urlSplit(end-1));
%             end
%             
%             % Plot image in visualisator
%             app.TabGroup.SelectedTab = app.OriginalImagesTab;
%             app.OriginalVisualisator.Visible = true;
% %             app.OriginalImageToVisualise = getShiftedCompositeImage(app.imagesCell);
% %             app.OriginalImageToVisualise = app.imagesCell(:,:,1);
%             app.plotType = 'original';
%             displayVisualisator(app)
            
            app.LampStep1.Color = [0 1 0];
            app.RegisterImagesButton.Enable = true;
            app.LampStep2.Color = [1 0 0];
            app.GetFStackButton.Enable = false;
            app.LampStep3.Color = [1 0 0];
            app.DeconvolveStacksButton.Enable = false;
            app.LampStep4.Color = [1 0 0];
            app.GOTORESULTSANALYSISAPPButton.Enable = false;
            app.EditFieldMaxShift.Value = "";
        end

        % Button pushed function: RegisterImagesButton
        function RegisterImagesButtonPushed(app, event)
%             splitImageCell = splitImages(app, app.registeredImage);
%             [app.registeredImagesCell, app.regCoeffs] = getRegisteredImages(app, splitImageCell);
%             
%             app.RegisteredImageToVisualise = getShiftedCompositeImage(app.registeredImages);
%             app.registeredImages = cat(3, app.registeredImages{:});
            app.unFocusResults = getOutOfFocusFrames(app.registeredImages, app.stdFactor);

%             app.TabGroup.SelectedTab = app.RegImagesTab;
%             app.plotType = 'registered';
%             displayVisualisator(app)
            app.plotType = 'unFocus';
            displayVisualisator(app)
            app.LampStep2.Color = [0 1 0];
            app.LampStep3.Color = [1 0 0];
            app.GetFStackButton.Enable = true;
            app.LampStep4.Color = [1 0 0];
            app.DeconvolveStacksButton.Enable = false;
            app.GOTORESULTSANALYSISAPPButton.Enable = false;
        end

        % Button pushed function: GetFStackButton
        function GetFStackButtonPushed(app, event)
            startFrame = app.WindowLengthSpinner.Value + app.WindowOffsetSpinner.Value;
            
            app.DFImage = zeros(size(app.registeredImages));
            sSize = size(app.registeredImages, 3);
            startPt = 1;
            
            for f = startFrame : sSize
                baseline = app.registeredImages(:,:, startPt:startPt + app.WindowLengthSpinner);
                baselineMean = mean(baseline,3);
                app.DFImage(:,:,f) = app.registeredImages(:,:,f) - baselineMean;
                startPt = startPt + 1;
            end
            app.DFImage(:,:,1:startFrame) = 0;

            app.DFImageToVisualise = max(app.DFImage, [], 3);
            app.TabGroup.SelectedTab = app.DFImageTab;
            app.plotType = 'DF';
            displayVisualisator(app)
            app.LampStep3.Color = [0 1 0];
            app.DeconvolveStacksButton.Enable = true;
            app.LampStep4.Color = [1 0 0];
            app.GOTORESULTSANALYSISAPPButton.Enable = false;
        end

        % Button pushed function: ShowHDImageButton
        function ShowHDImageButtonPushed(app, event)
            
        end

        % Button pushed function: GOTORESULTSANALYSISAPPButton
        function GOTORESULTSANALYSISAPPButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DeconvSnifferV10UIFigure = uifigure('Visible', 'off');
            app.DeconvSnifferV10UIFigure.Name = 'MATLAB App';
            app.Step1DefineExperimentNameandselectTIFFfilesPanel = uipanel(app.DeconvSnifferV10UIFigure);
            app.Step1DefineExperimentNameandselectTIFFfilesPanel.Title = 'Step 1: Define Experiment Name and select TIFF files';
            app.SelectFilesButton = uibutton(app.Step1DefineExperimentNameandselectTIFFfilesPanel, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesButtonPushed, true);
            app.SelectFilesButton.Text = 'Select Files';
            app.SelectedFilesTextAreaLabel = uilabel(app.Step1DefineExperimentNameandselectTIFFfilesPanel);
            app.SelectedFilesTextAreaLabel.Text = 'Selected Files';
            app.SelectedFilesTextArea = uitextarea(app.Step1DefineExperimentNameandselectTIFFfilesPanel);
            app.SelectedFilesTextArea.Editable = 'off';
            app.SelectedFilesTextArea.Value = {'No files selected yet.'};
            app.ExperimentNameEditFieldLabel = uilabel(app.Step1DefineExperimentNameandselectTIFFfilesPanel);
            app.ExperimentNameEditFieldLabel.Text = 'Experiment Name';
            app.ExperimentNameEditField = uieditfield(app.Step1DefineExperimentNameandselectTIFFfilesPanel, 'text');
            app.LampStep1 = uilamp(app.Step1DefineExperimentNameandselectTIFFfilesPanel);
            app.ResetValuesButton = uibutton(app.Step1DefineExperimentNameandselectTIFFfilesPanel, 'push');
            app.ResetValuesButton.Text = 'Reset Values';
            app.VisualisationPanel = uipanel(app.DeconvSnifferV10UIFigure);
            app.VisualisationPanel.Title = 'Visualisation Panel';
            app.TabGroup = uitabgroup(app.VisualisationPanel);
            app.OriginalImagesTab = uitab(app.TabGroup);
            app.OriginalImagesTab.Title = 'Original Images';
            app.OriginalVisualisator = uiaxes(app.OriginalImagesTab);
            app.OriginalVisualisator.Visible = 'off';
            app.RegImagesTab = uitab(app.TabGroup);
            app.RegImagesTab.Title = 'Reg. Images';
            app.RegisteredVisualisator = uiaxes(app.RegImagesTab);
            app.RegisteredVisualisator.Visible = 'off';
            app.IntensitySliderLabel = uilabel(app.RegImagesTab);
            app.IntensitySliderLabel.Visible = 'off';
            app.IntensitySliderLabel.Text = 'Intensity';
            app.IntensitySlider = uislider(app.RegImagesTab);
            app.IntensitySlider.Limits = [1 3];
            app.IntensitySlider.MajorTicks = [1 1.5 2 2.5 3];
            app.IntensitySlider.MajorTickLabels = {'1', '1.5', '2', '2.5', '3'};
            app.IntensitySlider.MinorTicks = [1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3];
            app.IntensitySlider.Visible = 'off';
            app.IntensitySlider.Value = 1;
            app.OutofFocusCheckTab = uitab(app.TabGroup);
            app.OutofFocusCheckTab.Title = 'Out of Focus Check';
            app.UnFocusPanel = uipanel(app.OutofFocusCheckTab);
            app.DFImageTab = uitab(app.TabGroup);
            app.DFImageTab.Title = 'DF Image';
            app.DFVisualizator = uiaxes(app.DFImageTab);
            app.DFVisualizator.Visible = 'off';
            app.ShowHDImageButton = uibutton(app.VisualisationPanel, 'push');
            app.ShowHDImageButton.ButtonPushedFcn = createCallbackFcn(app, @ShowHDImageButtonPushed, true);
            app.ShowHDImageButton.Text = 'Show HD Image';
            app.Step2ImageregistrationandinfocuscheckPanel = uipanel(app.DeconvSnifferV10UIFigure);
            app.Step2ImageregistrationandinfocuscheckPanel.Title = 'Step 2: Image registration and in-focus check';
            app.RegisterImagesButton = uibutton(app.Step2ImageregistrationandinfocuscheckPanel, 'push');
            app.RegisterImagesButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterImagesButtonPushed, true);
            app.RegisterImagesButton.Enable = 'off';
            app.RegisterImagesButton.Text = 'Register Images';
            app.LabelStep2 = uilabel(app.Step2ImageregistrationandinfocuscheckPanel);
            app.LabelStep2.Text = 'Horizontal and vertical allignment is performed to avoid signals displacement over time. Also un-focused frames are detected for future analysis.';
            app.LampStep2 = uilamp(app.Step2ImageregistrationandinfocuscheckPanel);
            app.MaxpixelsshiftedxyLabel = uilabel(app.Step2ImageregistrationandinfocuscheckPanel);
            app.MaxpixelsshiftedxyLabel.Text = 'Max. pixels shifted (x,y)';
            app.EditFieldMaxShift = uieditfield(app.Step2ImageregistrationandinfocuscheckPanel, 'text');
            app.EditFieldMaxShift.Editable = 'off';
            app.Step3CalculateFStacksPanel = uipanel(app.DeconvSnifferV10UIFigure);
            app.Step3CalculateFStacksPanel.Title = 'Step 3: Calculate ΔF Stacks';
            app.LabelStep3 = uilabel(app.Step3CalculateFStacksPanel);
            app.LabelStep3.Text = 'Dendritic branches are substracted from the images to isolete the signals and bleaching is corrected using a rolling window strategy. Please select the window offset and the window length.';
            app.LampStep3 = uilamp(app.Step3CalculateFStacksPanel);
            app.GetFStackButton = uibutton(app.Step3CalculateFStacksPanel, 'push');
            app.GetFStackButton.ButtonPushedFcn = createCallbackFcn(app, @GetFStackButtonPushed, true);
            app.GetFStackButton.Enable = 'off';
            app.GetFStackButton.Text = 'Get ΔF Stack';
            app.OffsetLabel = uilabel(app.Step3CalculateFStacksPanel);
            app.OffsetLabel.Text = 'Offset';
            app.WindowOffsetSpinner = uispinner(app.Step3CalculateFStacksPanel);
            app.WindowOffsetSpinner.Limits = [1 Inf];
            app.WindowOffsetSpinner.Value = 1;
            app.LengthLabel = uilabel(app.Step3CalculateFStacksPanel);
            app.LengthLabel.Text = 'Length';
            app.WindowLengthSpinner = uispinner(app.Step3CalculateFStacksPanel);
            app.WindowLengthSpinner.Limits = [1 Inf];
            app.WindowLengthSpinner.Value = 1;
            app.Step5ImageDeconvolutionPanel = uipanel(app.DeconvSnifferV10UIFigure);
            app.Step5ImageDeconvolutionPanel.Title = 'Step 5: Image Deconvolution';
            app.LabelStep4 = uilabel(app.Step5ImageDeconvolutionPanel);
            app.LabelStep4.Text = 'The Richardson-Lucy algorithm runs over the image stacks to isolate synapse signals determining space and temporal occurrence and signals intensities.';
            app.DeconvolveStacksButton = uibutton(app.Step5ImageDeconvolutionPanel, 'push');
            app.DeconvolveStacksButton.Enable = 'off';
            app.DeconvolveStacksButton.Text = 'Deconvolve Stacks';
            app.LampStep4 = uilamp(app.Step5ImageDeconvolutionPanel);
            app.LogsconsolePanel = uipanel(app.DeconvSnifferV10UIFigure);
            app.LogsconsolePanel.Title = 'Logs console';
            app.LogsTextArea = uitextarea(app.LogsconsolePanel);
            app.ResultsAnalysisPanel = uipanel(app.DeconvSnifferV10UIFigure);
            app.ResultsAnalysisPanel.Title = 'Results Analysis';
            app.GOTORESULTSANALYSISAPPButton = uibutton(app.ResultsAnalysisPanel, 'push');
            app.GOTORESULTSANALYSISAPPButton.ButtonPushedFcn = createCallbackFcn(app, @GOTORESULTSANALYSISAPPButtonPushed, true);
            app.GOTORESULTSANALYSISAPPButton.Enable = 'off';
            app.GOTORESULTSANALYSISAPPButton.Text = 'GO TO RESULTS ANALYSIS APP';
            app.LabelStep6 = uilabel(app.ResultsAnalysisPanel);
            app.LabelStep6.Text = 'Regions of interest and their traces information are exported using the deconvolution results. Also the ROIs can be evaluated interactively in the results analysis app.';
            % Show the figure after all components are created
            app.DeconvSnifferV10UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Deconv_Sniffer_SP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DeconvSnifferV10UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DeconvSnifferV10UIFigure)
        end
    end
end