classdef Results_Analyzis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Results_AnalyzisIFigure        matlab.ui.Figure
        BackgroundPanel                matlab.ui.container.Panel
        MinimumBackgroundValueEditField  matlab.ui.control.NumericEditField
        MinimumBackgroundValueEditFieldLabel  matlab.ui.control.Label
        manualMinBackgroundValField    matlab.ui.control.NumericEditField
        EstablishBackgroundManuallyCheckBox  matlab.ui.control.CheckBox
        ResultsPanel                   matlab.ui.container.Panel
        ExportProcessedStacksCheckBox  matlab.ui.control.CheckBox
        ButtonResults                  matlab.ui.control.Button
        SignalspertrialPanel           matlab.ui.container.Panel
        AxesSignalsImgs                matlab.ui.control.UIAxes
        SignalsProfilesPanel           matlab.ui.container.Panel
        ROIRadiusSlider                matlab.ui.control.Slider
        ROIRadiusSliderLabel           matlab.ui.control.Label
        AxesYAxisTraces                matlab.ui.control.UIAxes
        AxesDeconvTraces               matlab.ui.control.UIAxes
        AxesDFFTraces                  matlab.ui.control.UIAxes
        AxesXAxisTraces                matlab.ui.control.UIAxes
        AxesDFTraces                   matlab.ui.control.UIAxes
        ReleaseClustersPanel           matlab.ui.container.Panel
        DeletedLabelVal                matlab.ui.control.Label
        DiscardedLabel                 matlab.ui.control.Label
        SelectedLabelVal               matlab.ui.control.Label
        SelectedLabel                  matlab.ui.control.Label
        ButtonGetSynapseClusters       matlab.ui.control.Button
        TimeLapseLabel                 matlab.ui.control.Label
        TimeLapseSlider                matlab.ui.control.Slider
        EditFieldThresholdFactor       matlab.ui.control.NumericEditField
        ThresholdFactorEditFieldLabel  matlab.ui.control.Label
        LabelY                         matlab.ui.control.Label
        YLabel                         matlab.ui.control.Label
        LabelX                         matlab.ui.control.Label
        XLabel                         matlab.ui.control.Label
        ThresholdValueLabel            matlab.ui.control.Label
        EditFieldThresholdVal          matlab.ui.control.NumericEditField
        ButtonGetReleaseClusters       matlab.ui.control.Button
        TrialDropDown                  matlab.ui.control.DropDown
        TrialDropDownLabel             matlab.ui.control.Label
        AxesSynapseClusters            matlab.ui.control.UIAxes
        AxesDBSCAN                     matlab.ui.control.UIAxes
        ResponsesHistogramPanel        matlab.ui.container.Panel
        STDEditField                   matlab.ui.control.NumericEditField
        STDEditFieldLabel              matlab.ui.control.Label
        MeanEditField                  matlab.ui.control.NumericEditField
        MeanEditFieldLabel             matlab.ui.control.Label
        PeaksHistogramAxes             matlab.ui.control.UIAxes
    end


    properties (Access = private)
        % ----- Images Info
        experimentType
        experimentName
        imagesToVisualize
        deconvStacks
        deltaFStacks
        deltaFOverFstacks
        baselinesStack
        registeredStacks
        regCoeffs
        UnbleachingResults
        ThresholdVal
        releaseClusters
        synapseClusters
        selectedSynapses
        centersOfGravityRC
        SynapsesCoG
        Backgrs
        vertices
        unbleachedNormalizedROIs
        xClicked
        yClicked
        % ----- Constants
        triggerTime
        pseudoTriggerTime
        initFrames
        endFrames
        unusualAmpInitFrames
        windowOffset
        windowLength
        overlappedFrames
        stdFactor
        epsilonRC
        epsilonSC
        radiusSC
        gaussSigmaSA
        pixelFactorNm
        minPts % MinPts for DBSCAN
        trilaColors = {[0,0.447,0.741, 0.3]
            [0.85,0.325,0.098,1]};
        framesRadio = 100;
        isReleaseClustersUpdated = false;
    end

    methods (Access = private)

        function calculateThresholdValue(app)
            switch app.experimentType
                case 0 % Triggered
                    numStacks = length(app.deconvStacks);
                    allPos = zeros(1, numStacks);
                    for i = 1 : numStacks
                        temp = app.deconvStacks{i}(:,:,app.initFrames:app.endFrames);
                        temp = temp(temp > 0);
                        allPos(i) = prctile(temp, 95, 'all');
                    end
                case 1 %Spontaneous
                    temp = app.deconvStacks(app.deconvStacks > 0);
                    allPos = prctile(temp, 95, 'all');
            end

            threshold = mean(allPos,'omitnan');
            app.ThresholdVal = threshold * app.EditFieldThresholdFactor.Value;
        end

        function deconvConcat = concatDeconvStacks(~, deconvStacks, numStacks, overlappedFrames)
            deconvConcat = [];
            for i = 1 : numStacks
                if i < numStacks
                    deconvConcat = cat(3, deconvConcat, deconvStacks{i}(:,:,1:end-overlappedFrames-1));
                else
                    deconvConcat = cat(3, deconvConcat, deconvStacks{i});
                end
            end
        end

        function plotReleaseEvents(app)
            switch app.experimentType
                case 0 % Triggered
                    eventsPerTrial = app.releaseClusters{app.TrialDropDown.Value}; % For one trial
                    try
                        eventsPerTrial = eventsPerTrial(eventsPerTrial(:,4) == app.triggerTime, :); % Events in trigger time
                        palleteSize = size(app.deltaFStacks{1},1);

                        [colors, ~] = getColors(eventsPerTrial(:,2:3),  palleteSize);
                        amps = rescale(eventsPerTrial(:,5), 20, 200);
                        markType = 'o';

                        scatter(app.AxesDBSCAN, eventsPerTrial(:,2), eventsPerTrial(:,3), amps, colors, markType);
                        xlim(app.AxesDBSCAN, [0 palleteSize])
                        ylim(app.AxesDBSCAN, [0 palleteSize])
                        set(app.AxesDBSCAN, 'YDir','reverse')
                    catch
                        cla(app.AxesDeconvTraces);
                        cla(app.AxesDBSCAN)
                        uialert(app.Results_AnalyzisIFigure,['No Deconv. Peaks found in this trial', newline, 'Please try a smaller threshold'],'No Peaks Found', 'Icon','info');
                    end
                case 1 %Spontaneous
                    palleteSize = size(app.deltaFStacks,1);
                    try
                        selectedEvents = app.releaseClusters{1}(app.releaseClusters{1}(:,4) < app.TimeLapseSlider.Value + app.framesRadio, :);
                        selectedEvents = selectedEvents(selectedEvents(:,4) > app.TimeLapseSlider.Value - app.framesRadio, :);

                        [colors, ~] = getColors(selectedEvents(:,2:3), palleteSize);
                        amps = rescale(selectedEvents(:,5), 20, 200);
                        markType = 'o';

                        scatter(app.AxesDBSCAN, selectedEvents(:,2), selectedEvents(:,3), amps, colors, markType);
                        xlim(app.AxesDBSCAN, [0 palleteSize])
                        ylim(app.AxesDBSCAN, [0 palleteSize])
                        set(app.AxesDBSCAN, 'YDir','reverse')
                    catch
                        cla(app.AxesDeconvTraces);
                        cla(app.AxesDBSCAN)
                        uialert(app.Results_AnalyzisIFigure,['No Deconv. Peaks found in this trial', newline, 'Please try a smaller threshold'],'No Peaks Found', 'Icon','info');
                    end
            end
        end

        function plotReleaseEventsCoG(app)
            switch app.experimentType
                case 0 % Triggered
                    cogsPerTrial = app.centersOfGravityRC{app.TrialDropDown.Value};
                    try
                        cogsPerTrial = cogsPerTrial(cogsPerTrial(:,4) == app.triggerTime, :); % CoG in trigger time
                        palleteSize = size(app.deltaFStacks{1},1);

                        [colors, ~] = getColors(cogsPerTrial(:,2:3),  palleteSize);
                        amps = rescale(cogsPerTrial(:,5), 20, 200);
                        markType = 's';

                        scatter(app.AxesCenterOfMass, cogsPerTrial(:,2), cogsPerTrial(:,3), amps, colors, markType);
                        xlim(app.AxesCenterOfMass, [0 palleteSize])
                        ylim(app.AxesCenterOfMass, [0 palleteSize])
                        set(app.AxesCenterOfMass, 'YDir','reverse')
                    catch
                        cla(app.AxesCenterOfMass)
                    end
                case 1 %Spontaneous
                    palleteSize = size(app.deltaFStacks,1);
                    try
                        selectedCoGs = app.centersOfGravityRC{1}(app.centersOfGravityRC{1}(:,4) < app.TimeLapseSlider.Value + app.framesRadio, :);
                        selectedCoGs = selectedCoGs(selectedCoGs(:,4) > app.TimeLapseSlider.Value - app.framesRadio, :);

                        [colors, ~] = getColors(selectedCoGs(:,2:3),  palleteSize);
                        amps = rescale(selectedCoGs(:,5), 20, 200);
                        markType = 's';

                        scatter(app.AxesCenterOfMass, selectedCoGs(:,2), selectedCoGs(:,3), amps, colors, markType);
                        xlim(app.AxesCenterOfMass, [0 palleteSize])
                        ylim(app.AxesCenterOfMass, [0 palleteSize])
                        set(app.AxesCenterOfMass, 'YDir','reverse')
                    catch
                        cla(app.AxesCenterOfMass)
                    end
            end
        end

        function plotProfiles(app)
            if ~isempty(app.LabelX.Text) && ~isempty(app.LabelY.Text)
                r = app.ROIRadiusSlider.Value;

                if app.experimentType == 0
                    numStacks = length(app.deconvStacks);
                    n = size(app.deconvStacks{1},3);
                    mask = getGaussianMask(app, (r*2)+1);
                    for i = 1 : numStacks
                        colorInd = 1;
                        lineWidth = 0.1;
                        if i == app.TrialDropDown.Value
                            lineWidth = 1;
                            colorInd = 2;
                        end
                        try
                            % Deconv. Profile
                            timeProfile = reshape(app.deconvStacks{i}(app.yClicked, app.xClicked, :),1,[]);
                            plot(app.AxesDeconvTraces, 1:n, timeProfile, "Color", app.trilaColors{colorInd}, "LineWidth", lineWidth)
                            hold(app.AxesDeconvTraces, 'on')
                            yline(app.AxesDeconvTraces, app.EditFieldThresholdVal.Value)
                            xlim(app.AxesDeconvTraces,[1, length(timeProfile)])

                            % DF. Profile
                            timeProfile = zeros(1,size(app.deltaFStacks{i},3));
                            for j = 1 : size(app.deltaFStacks{i},3)
                                timeProfile(j) = sum(app.deltaFStacks{i}(round(app.yClicked)-r : round(app.yClicked)+r,...
                                    round(app.xClicked)-r : round(app.xClicked)+r, j) .* mask, 'all');
                            end
                            plot(app.AxesDFTraces, 1:n, timeProfile, "Color", app.trilaColors{colorInd}, "LineWidth", lineWidth)
                            hold(app.AxesDFTraces, 'on')
                            xlim(app.AxesDFTraces,[1, length(timeProfile)])

                            % DF/F. Profile
                            timeProfile = zeros(1,size(app.deltaFOverFstacks{i},3));
                            for j = 1 : size(app.deltaFOverFstacks{i},3)
                                timeProfile(j) = sum(app.deltaFOverFstacks{i}(round(app.yClicked)-r : round(app.yClicked)+r,...
                                    round(app.xClicked)-r : round(app.xClicked)+r, j) .* mask, 'all');
                            end
                            plot(app.AxesDFFTraces, 1:n, timeProfile, "Color", app.trilaColors{colorInd}, "LineWidth", lineWidth)
                            hold(app.AxesDFFTraces, 'on')
                            xlim(app.AxesDFFTraces,[1, length(timeProfile)])
                        catch ex
                            getReport(ex)
                            uialert(app.Results_AnalyzisIFigure,['ROI Radius out of Image Boundaries.', newline, 'Please try a smaller radius'],'ROI out of Bounds');
                            cla(app.AxesDeconvTraces);
                            cla(app.AxesDFTraces);
                            cla(app.AxesDFFTraces);
                            cla(app.AxesXAxisTraces);
                            cla(app.AxesYAxisTraces);
                            break
                        end

                        spatialProfile = getSpatialProfile(app, app.deltaFStacks{i}, 'X', []);
                        lim = (round(app.xClicked)-10:round(app.xClicked)+10);
                        plot(app.AxesXAxisTraces, lim, spatialProfile, "Color", app.trilaColors{colorInd}, "LineWidth", lineWidth)
                        hold(app.AxesXAxisTraces, 'on')
                        xlim(app.AxesXAxisTraces, [round(app.xClicked)-10, round(app.xClicked)+10])

                        spatialProfile = getSpatialProfile(app, app.deltaFStacks{i}, 'Y', []);
                        lim = (round(app.yClicked)-10 : round(app.yClicked)+10);
                        plot(app.AxesYAxisTraces, lim, spatialProfile, "Color", app.trilaColors{colorInd}, "LineWidth", lineWidth)
                        hold(app.AxesYAxisTraces, 'on')
                        xlim(app.AxesYAxisTraces, [round(app.yClicked)-10, round(app.yClicked)+10])
                    end
                    hold(app.AxesDeconvTraces, "off")
                    hold(app.AxesDFTraces, 'off')
                    hold(app.AxesDFFTraces, 'off')
                    hold(app.AxesXAxisTraces, 'off')
                    hold(app.AxesYAxisTraces, 'off')
                else
                    n = 21;
                    mask = getGaussianMask(app, (r*2)+1);
                    selectedPoints = app.releaseClusters{1}(app.releaseClusters{1}(:,3) == app.yClicked, :);
                    selectedPoints = selectedPoints(selectedPoints(:,2) == app.xClicked, :);

                    for i = 1 : size(selectedPoints,1)
                        colorInd = 1;
                        lineWidth = 0.5;
                        if selectedPoints(i,4) < (app.TimeLapseSlider.Value + app.framesRadio) && selectedPoints(i,4) > (app.TimeLapseSlider.Value - app.framesRadio)
                            colorInd = 2;
                            lineWidth = 1;
                        end
                        try
                            % Deconv. Profile
                            timeProfile = reshape(app.deconvStacks(selectedPoints(i,3), selectedPoints(i,2),...
                                selectedPoints(i,4) - 10: selectedPoints(i,4) + 10), 1, []);
                            plot(app.AxesDeconvTraces, 1:n, timeProfile, "Color", app.trilaColors{colorInd}, "LineWidth", lineWidth)
                            hold(app.AxesDeconvTraces, 'on')
                            yline(app.AxesDeconvTraces, app.EditFieldThresholdVal.Value)
                            xlim(app.AxesDeconvTraces,[1, length(timeProfile)])

                            % DF. Profile
                            timeProfile = zeros(1, n);
                            for j = 1 : n
                                timeProfile(j) = sum(app.deltaFStacks(round(app.yClicked)-r : round(app.yClicked)+r,...
                                    round(app.xClicked)-r : round(app.xClicked)+r, selectedPoints(i,4) - 11 + j) .* mask, 'all');
                            end
                            plot(app.AxesDFTraces, 1:n, timeProfile, "Color", app.trilaColors{colorInd}, "LineWidth", lineWidth)
                            hold(app.AxesDFTraces, 'on')
                            xlim(app.AxesDFTraces,[1, length(timeProfile)])

                            % DF/F. Profile
                            timeProfile = zeros(1, n);
                            for j = 1 : n
                                timeProfile(j) = sum(app.deltaFOverFstacks(round(app.yClicked)-r : round(app.yClicked)+r,...
                                    round(app.xClicked)-r : round(app.xClicked)+r, selectedPoints(i,4) - 11 + j) .* mask, 'all');
                            end
                            plot(app.AxesDFFTraces, 1:n, timeProfile, "Color", app.trilaColors{colorInd}, "LineWidth", lineWidth)
                            hold(app.AxesDFFTraces, 'on')
                            xlim(app.AxesDFFTraces,[1, length(timeProfile)])
                        catch ex
                            getReport(ex)
                            uialert(app.Results_AnalyzisIFigure,['ROI Radius out of Image Boundaries.', newline, 'Please try a smaller radius'],'ROI out of Bounds');
                            cla(app.AxesDeconvTraces);
                            cla(app.AxesDFTraces);
                            cla(app.AxesDFFTraces);
                            cla(app.AxesXAxisTraces);
                            cla(app.AxesYAxisTraces);
                        end
                        spatialProfile = getSpatialProfile(app, app.deltaFStacks, 'X', selectedPoints(i,4));
                        lim = (round(app.xClicked)-10:round(app.xClicked)+10);
                        plot(app.AxesXAxisTraces, lim, spatialProfile, "Color", app.trilaColors{colorInd}, "LineWidth", lineWidth)
                        hold(app.AxesXAxisTraces, 'on')
                        xlim(app.AxesXAxisTraces, [round(app.xClicked)-10, round(app.xClicked)+10])

                        spatialProfile = getSpatialProfile(app, app.deltaFStacks, 'Y', selectedPoints(i,4));
                        lim = (round(app.yClicked)-10 : round(app.yClicked)+10);
                        plot(app.AxesYAxisTraces, lim, spatialProfile, "Color", app.trilaColors{colorInd}, "LineWidth", lineWidth)
                        hold(app.AxesYAxisTraces, 'on')
                        xlim(app.AxesYAxisTraces, [round(app.yClicked)-10, round(app.yClicked)+10])
                    end
                    hold(app.AxesDeconvTraces, "off")
                    hold(app.AxesDFTraces, 'off')
                    hold(app.AxesDFFTraces, 'off')
                    hold(app.AxesXAxisTraces, 'off')
                    hold(app.AxesYAxisTraces, 'off')
                end
            end
        end

        function spatialProfile = getSpatialProfile(app, stack, prof, timeCoord)
            x = round(app.xClicked);
            y = round(app.yClicked);
            if app.experimentType == 0
                n = size(app.deconvStacks{1},1);
                releaseTime = app.triggerTime;
            else
                n = size(app.deconvStacks,1);
                releaseTime = timeCoord;
            end
            radius = 10;
            spatialProfile = NaN(1,21);
            [leftPos, rightPos, downPos, upPos] = deal(1, 21, 1, 21);
            [leftLim, rightLim, downLim, upLim] = deal(1, n, 1, n);
            switch prof
                case 'X'
                    if x + radius > n
                        rightPos = 21 - (x + radius - n);
                    else
                        rightLim = x + radius;
                    end
                    if x - radius < 1
                        leftPos = abs(x - radius) + 2;
                        rightLim = x + radius;
                    else
                        leftLim = x - radius;
                    end
                    spatialProfile(leftPos:rightPos) = stack(y, leftLim:rightLim, releaseTime);
                case 'Y'
                    if y + radius > n
                        upPos = 21 - (y + radius - n);
                    else
                        upLim = y + radius;
                    end
                    if y - radius < 1
                        downPos = abs(y - radius) + 2;
                        upLim = y + radius;
                    else
                        downLim = y - radius;
                    end
                    spatialProfile(downPos:upPos) = stack(downLim:upLim, x, releaseTime)';
            end
        end

        function plotMountedImages(app, xPos, yPos)
            submatrixSize = 79;
            if app.experimentType == 0
                [row, col] = calculateGridFromStacksNumber(length(app.deltaFStacks), "DFMontage");
                imageSize = size(app.deltaFStacks{1},1);
                comp = zeros(submatrixSize, submatrixSize, size(app.deltaFStacks{1},3));

                for i = 1 : length(app.deltaFStacks)
                    signalImage = getSignalImage(app.deltaFStacks{i}, [xPos, yPos], submatrixSize, imageSize, app.triggerTime);
                    comp(:,:,i) = signalImage;
                end
                maxComp = max(comp(:));
                minComp = min(comp(:));
                for i = 1 : length(app.deltaFStacks)
                    comp(:,:,i) = mat2gray(comp(:,:,i), [minComp maxComp]);
                end
                montage(comp, 'Size', [row col], 'BorderSize', [10 10], 'Parent', app.AxesSignalsImgs);
            else
                selectedPoints = app.releaseClusters{1}(app.releaseClusters{1}(:,3) == app.yClicked, :);
                selectedPoints = selectedPoints(selectedPoints(:,2) == app.xClicked, :);
                s = size(selectedPoints, 1);
                if s == 1
                    numImages = [1 1];
                elseif s == 2
                    numImages = [1 2];
                elseif s <= 4
                    numImages = [2 2];
                else
                    numImages = [2 3];
                end
                imageSize = size(app.deltaFStacks,1);
                comp = zeros(submatrixSize, submatrixSize, size(selectedPoints, 1));
                for i = 1 : size(selectedPoints, 1)
                    signalImage = getSignalImage(app.deltaFStacks, [xPos, yPos], submatrixSize, imageSize, selectedPoints(i,4));
                    comp(:,:,i) = mat2gray(signalImage);
                end
                montage(comp, 'Size', numImages, 'BorderSize', [10 10], 'Parent', app.AxesSignalsImgs);
            end
        end

        function plotSynapseClusters(app, allCoGs)
            gscatter(app.AxesSynapseClusters, allCoGs(:,2), allCoGs(:,3), allCoGs(:,6), cell2mat(app.trilaColors), 'o', 1);
            if app.experimentType == 0
                xlim(app.AxesSynapseClusters, [0 size(app.deltaFStacks{1},1)])
                ylim(app.AxesSynapseClusters, [0 size(app.deltaFStacks{1},2)])
            else
                xlim(app.AxesSynapseClusters, [0 size(app.deltaFStacks,1)])
                ylim(app.AxesSynapseClusters, [0 size(app.deltaFStacks,2)])
            end
            legend(app.AxesSynapseClusters, 'Selected Synapses', 'Discarded Synapses', 'Location', 'best')
            set(app.AxesSynapseClusters, 'YDir','reverse')
        end

        function plotDFFPeaksHistogram(app)
            r = 1; 
            mask = getGaussianMask(app, (r*2)+1); 
            z = size(app.deltaFOverFstacks{1},3);
            allDFFPeaks = zeros(1);
            cont = 1;
            for R = 1 : size(app.selectedSynapses.CoG, 1)
                [x, y] = deal(app.selectedSynapses.CoG(R,2), app.selectedSynapses.CoG(R,3));
                trials = cell2mat(app.selectedSynapses.trials(R,2));
                allTimeProfiles = zeros(length(trials), z);
                for T = 1 : length(trials)
                    temp = app.deltaFOverFstacks{trials(T)};
                    for j = 1 : z
                        allTimeProfiles(T,j) = sum(temp(round(y)-r : round(y)+r,...
                            round(x)-r : round(x)+r, j) .* mask, 'all');
                    end
                    allDFFPeaks(cont) = allTimeProfiles(T,app.triggerTime);
                    cont = cont + 1;
                end
            end

            histogram(app.PeaksHistogramAxes, allDFFPeaks, round(cont/2))
            app.MeanEditField.Value = double(mean(allDFFPeaks));
            app.STDEditField.Value = double(std(allDFFPeaks));
        end

        function mask = getGaussianMask(~, n)
            w = gausswin(n);
            wf = (sum(w) -1) / numel(w);
            nw = w - wf;
            [maskr, maskc] = meshgrid(nw, nw);
            mask = maskr.*maskc;
        end

        function [unbleachedNormalizedROIs, unbleachedROIs] = getROIsAverages(~, unbleachedNormalizedROIs, unbleachedROIs)
            for i = 1 : size(unbleachedROIs, 1)
                M = mean(unbleachedROIs{i});
                M(1) = 2;
                realSignals = unbleachedROIs{i}(unbleachedROIs{i}(:,1) == 1, :);
                Ms = mean(realSignals, 1, "omitnan");
                Ms(1) = 3;
                unbleachedROIs{i} = cat(1, M, Ms, unbleachedROIs{i});

                M = mean(unbleachedNormalizedROIs{i});
                M(1) = 2;
                realSignals = unbleachedNormalizedROIs{i}(unbleachedNormalizedROIs{i}(:,1) == 1, :);
                Ms = mean(realSignals, 1, 'omitnan');
                Ms(1) = 3;
                unbleachedNormalizedROIs{i} = cat(1, M, Ms, unbleachedNormalizedROIs{i});
            end
        end

        function exportImages(app, selpath)
            % Plot Before and After Registration
            stacksNumber = length(app.registeredStacks);
            Ticks = linspace(0,stacksNumber+1, stacksNumber+1);
            TicksLabels = string(linspace(0,stacksNumber, stacksNumber+1));
            if ~isempty(app.imagesToVisualize.Original)
                f = figure('units','normalized','outerposition',[0 0 1 1], 'Visible','off');
                sgtitle("Images Before and After Registration")
                ax(1) = subplot(1,2,1);
                imshow(app.imagesToVisualize.Original)
                cmap = hsv(stacksNumber);
                colormap(ax(1), cmap)
                c = colorbar;
                c.Ticks = Ticks/max(Ticks);
                c.TickLabels = TicksLabels;
                ylabel(c,"Trials");
                title("Raw Data Composite Image")
                pbaspect([1 1 1])
                ax(2) = subplot(1,2,2);
                imshow(app.imagesToVisualize.Registered)
                colormap(ax(2), cmap)
                c = colorbar;
                c.Ticks = Ticks/max(Ticks);
                c.TickLabels = TicksLabels;
                ylabel(c,"Trials");
                title("Registered Images Composite Image")
                pbaspect([1 1 1])
            
                exportgraphics(f,selpath + "/1. Registered Composite.jpg",'Resolution',400)
                close(f)
            else
                f = figure('units','normalized','outerposition',[0 0 1 1], 'Visible','off');

                sgtitle("Image After Registration")
                cmap = hsv(stacksNumber);
                RGB = mat2gray(getShiftedCompositeImage(app.registeredStacks));
                imshow(RGB)
                colormap(cmap)
                c = colorbar;
                c.Ticks = Ticks/max(Ticks);
                c.TickLabels = TicksLabels;
                ylabel(c,"Trials");
                title("Registered Images Composite Image")
            
                exportgraphics(f,selpath + "/1. Registered Composite.jpg",'Resolution',400)
                close(f)
            end

            % Export Registration Coefficients
            if ~isempty(app.regCoeffs)
                f = figure('units','normalized','outerposition',[0 0 1 1], 'Visible','off');
                sgtitle("Pixels Registered")
                s1 = subplot(211);
                plot(app.regCoeffs(2,:))
                s2 = subplot(212);
                plot(app.regCoeffs(1,:))
                grid([s1, s2], "minor")
                xlabel([s1, s2], "Frames")
                ylabel([s1, s2], "Pixels")
                title(s1, "Y-Axis Registration")
                title(s2,"X-Axis Registration")

                exportgraphics(f,selpath + "/2. Frames Shifting.jpg",'Resolution',400)
                close(f)
            end

            % All Synapses
            f = figure('units','normalized','outerposition',[0 0 1 1], 'Visible','off');
            M = rgb2gray(app.imagesToVisualize.Registered);
            bkgArea = M(app.vertices(1,2):app.vertices(2,2),app.vertices(1,1):app.vertices(3,1));
            bkgMean = mean(bkgArea(:));
            imshow(M,[bkgMean, max(app.imagesToVisualize.Registered(:))])
            hold on
            gscatter(app.SynapsesCoG(:,2), app.SynapsesCoG(:,3), app.SynapsesCoG(:,6), cell2mat(app.trilaColors), 'o', 2);
            xlim([0 size(app.deltaFStacks{1},1)])
            ylim([0 size(app.deltaFStacks{1},2)])
            grid("minor")
            legend('Selected Synapses', 'Discarded Synapses', 'Location', 'best', 'FontSize',2)
            title("Selected and Discarded Synapses")
            set(gca, 'YDir','reverse')

            exportgraphics(f,selpath + "/3. Selected and Discarded Synapses.jpg",'Resolution',400)
            close(f)

            % Selected Synapses
            nrTrialsTriggered = zeros(length(app.selectedSynapses.CoG), 1);
            for i = 1 : length(app.selectedSynapses.CoG)
                nrTrialsTriggered(i) = length(app.selectedSynapses.trials{i,2});
            end
            f = figure('units','normalized','outerposition',[0 0 1 1], 'Visible','off');
            imshow(M,[bkgMean, max(app.imagesToVisualize.Registered,[],"all")])
            hold on
            gscatter(app.selectedSynapses.CoG(:,2), app.selectedSynapses.CoG(:,3),...
                nrTrialsTriggered*(100/stacksNumber), jet(size(unique(nrTrialsTriggered),1)), "o", 2);
            xlim([0 size(app.deltaFStacks{1},1)])
            ylim([0 size(app.deltaFStacks{1},2)])
            grid("minor")
            leg = legend(string(unique(nrTrialsTriggered)*(100/stacksNumber))+"%", 'Location', 'best', 'FontSize',2);
            title("Selected Synapses")
            title(leg, 'Release Probability')
            set(gca, 'YDir','reverse')

            exportgraphics(f,selpath + "/4. Selected Synapses.jpg",'Resolution',400)
            close(f)

            % Synapse Areas
            f = figure('units','normalized','outerposition',[0 0 1 1], 'Visible','off');
            histogram(app.selectedSynapses.CoG(:,6), numel(unique(app.selectedSynapses.CoG(:,6))))
            grid("minor")
            xlabel('Area [\mum]','interpreter','Tex')
            ylabel("Counts")
            title("Synapses Areas")

            exportgraphics(f,selpath + "/5. Synapse Areas Histogram.jpg",'Resolution',400)
            close(f)

            % Release Probabilities
            f = figure('units','normalized','outerposition',[0 0 1 1], 'Visible','off');
            hold on
            nrTrialsTriggered = nrTrialsTriggered/stacksNumber*100;
            labels = (1:stacksNumber) / stacksNumber * 100;
            info = categorical(nrTrialsTriggered(nrTrialsTriggered == (100/stacksNumber)),(100/stacksNumber),"Ordinal",true);
            histogram(info)
            info = categorical(nrTrialsTriggered(nrTrialsTriggered > (100/stacksNumber)),labels,"Ordinal",true);
            histogram(info)
            xlabel("(%) Release Probability"); ylabel("Counts")
            h = legend("Responses in a single trial", "Responses in multiple trials");
            pos = h.Position;
            pos(2) = pos(2) - .15;
            annotation('textbox',pos,'String',"Mean Release Probability: " +...
                string(mean(nrTrialsTriggered)) + "%",'FitBoxToText','on', 'Color','r', 'FontSize', 8);
            grid on; grid minor; axis tight

            exportgraphics(f,selpath + "/6. Release Probabilities Histogram.jpg",'Resolution',400)
            close(f)

            % Synapse Traces and Amplitude
            % Only Responses
            f = figure('units','normalized','outerposition',[0 0 1 1], 'Visible','off'); hold on;
            allSynapses = []; 
            for t = 1 : length(app.unbleachedNormalizedROIs)
                allSynapses(end+1,:) = app.unbleachedNormalizedROIs{t}(app.unbleachedNormalizedROIs{t}(:,1) == 3, 2:end);
            end
            avg = mean(allSynapses);
            M = max(avg);
            plot(allSynapses', 'Color', app.trilaColors{1});
            yline(M, 'g');
            annotation('textbox',[.25 .24 .2 .2], 'String',...
                "Amplitude = " + M, 'FitBoxToText', 'on');
            axis tight; grid minor; grid on; xlabel("time"); ylabel("Amplitude");
            title(["All Synapse Traces", "Only Response Trials Average"])
            exportgraphics(f,selpath + "/7. Synapse Traces and average.jpg",'Resolution',400)
            close(f)
            % Including Failures
            f = figure('units','normalized','outerposition',[0 0 1 1], 'Visible','off'); hold on;
            allSynapses = zeros(length(app.unbleachedNormalizedROIs), size(app.unbleachedNormalizedROIs{1},2)-1); 
            for t = 1 : length(app.unbleachedNormalizedROIs)
                allSynapses(t,:) = app.unbleachedNormalizedROIs{t}(app.unbleachedNormalizedROIs{t}(:,1) == 2, 2:end);
            end
            avg = mean(allSynapses);
            M = max(avg);
            plot(allSynapses', 'Color', app.trilaColors{1});
            yline(M, 'g');
            annotation('textbox',[.25 .24 .2 .2], 'String',...
                "Amplitude = " + M, 'FitBoxToText', 'on');
            axis tight; grid minor; grid on; xlabel("time"); ylabel("Amplitude");
            title(["All Synapse Traces", "All Trials Average"])
            exportgraphics(f,selpath + "/8. Synapse Traces and average (Failures incl.).jpg",'Resolution',400)
            close(f)

            % Synapse Traces
            synapseNrs = app.selectedSynapses.CoG(:,1);
            selpath = selpath + "Synapse Traces\";
            mkdir(selpath)
            [row, col] = calculateGridFromStacksNumber(stacksNumber);
            for R = 1 : length(app.unbleachedNormalizedROIs)
                f = figure('units','normalized','outerposition',[0 0 1 1], 'Visible','off');
                sgtitle("Synapse nr. " + synapseNrs(R))
                for t = 3 : size(app.unbleachedNormalizedROIs{R}, 1)
                    subplot(row, col, t-2)
                    if app.unbleachedNormalizedROIs{R}(t,1) == 1
                        plot(app.unbleachedNormalizedROIs{R}(t,2:end), 'Color', [0.85,0.325,0.098])
                    else
                        plot(app.unbleachedNormalizedROIs{R}(t,2:end), 'Color', [0,0.447,0.741])
                    end
                    axis tight; grid minor; ylim([0.9, 1.4])
                    title("Trial " + string(t-2))
                end
                subplot(row, col, t-1)
                plot(app.unbleachedNormalizedROIs{R}(1,2:end), 'Color', 'k', 'LineWidth',1)
                axis tight; grid minor; ylim([0.9, 1.4]); title("All Trials Average")
                subplot(row, col, t)
                plot(app.unbleachedNormalizedROIs{R}(2,2:end), 'Color', [0.85,0.325,0.098], 'LineWidth',1)
                axis tight; grid minor; ylim([0.9, 1.4]); title("Responses only Average")
                subplot(row, col, t+1)
                plot(mean(app.unbleachedNormalizedROIs{R}(app.unbleachedNormalizedROIs{R}(:,1) == 0, 2:end)), 'Color', [0,0.447,0.741], 'LineWidth',1)
                axis tight; grid minor; ylim([0.9, 1.4]); title("Failures only Average")

                exportgraphics(f,selpath + "Synapse nr. " + string(synapseNrs(R)) +".jpg",'Resolution',80)
                close(f)
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, vararguin)
            set(app.Results_AnalyzisIFigure, 'pointer', 'watch')
            f = uiprogressdlg(app.Results_AnalyzisIFigure, 'Title','Please wait...',...
                'Message', 'Loading experiments data');
            drawnow;

            paramsMap = getParametersMap(); % Initializing Constants
            app.experimentType = paramsMap('experimentType');
            threshFactor = paramsMap('threshFactor');
            epsilonRCnm = paramsMap('epsilonRCnm');
            epsilonSCnm = paramsMap('epsilonSCnm');
            gaussSigmaSAnm = paramsMap('gaussSigmaSA');
            radiusSCnm = paramsMap('radiusSCnm');
            app.pixelFactorNm = paramsMap('pixelFactorNm');
            app.minPts = paramsMap('minPts');
            app.epsilonRC = epsilonRCnm/app.pixelFactorNm;
            app.epsilonSC = epsilonSCnm/app.pixelFactorNm;
            app.gaussSigmaSA = gaussSigmaSAnm/app.pixelFactorNm;
            app.radiusSC = radiusSCnm/app.pixelFactorNm;

            app.EditFieldThresholdFactor.Value = threshFactor;

            % Triggered Events Experiment
            if app.experimentType == 0
                load("data\Triggered Kernel.mat")
                app.triggerTime = paramsMap('triggerTime');
                app.pseudoTriggerTime = paramsMap("pseudoTriggerTime");
                app.TrialDropDown.Visible = true;

                if nargin == 1
                    if ispc
                        workspace = 'data\Results Workspace.mat';
                    elseif ismac || isunix
                        workspace = 'data/Results Workspace.mat';
                    end
                    myVars = {'experiment', 'deconvStacks', 'regImagesCellArray', 'regCoeffs', 'imagesToVisualize'};
                    load(workspace, myVars{:})

                    app.experimentName = experiment;
                    app.initFrames = paramsMap('initFrames');
                    app.endFrames = paramsMap('baselineEndFrame');
                    app.unusualAmpInitFrames = paramsMap('initFrames');
                    app.registeredStacks = regImagesCellArray;
                    app.deconvStacks = deconvStacks;

                    if ~exist("regCoeffs", 'var')
                        warning("No Registration/Shifting Data Found")
                        app.regCoeffs = [];
                    else
                        app.regCoeffs = regCoeffs;
                    end
                    if ~exist("imagesToVisualize", 'var')
                        warning("No Raw Data Composite Image Found")
                        app.imagesToVisualize.Original = [];
                    else
                        app.imagesToVisualize = imagesToVisualize;
                    end

                else
                    app.experimentName = vararguin{1};
                    app.deconvStacks = vararguin{8};
                    app.registeredStacks = vararguin{6};
                    app.unusualAmpInitFrames = vararguin{3};
                    app.initFrames = vararguin{4};
                    app.endFrames = vararguin{5};
                    app.regCoeffs = vararguin{7};
                    app.imagesToVisualize = vararguin{2};
                end

                [unbleachedImages, meanPercentDiff, allAvgBefore, ...
                    allAvgAfterItrial, allAvgAfterWhole, allFits, allCoeffs]...
                    = getImagesWithoutBleaching(app.registeredStacks,...
                    app.unusualAmpInitFrames, app.triggerTime);

                app.UnbleachingResults.meanPercentDiff = meanPercentDiff;
                app.UnbleachingResults.avgBefore = allAvgBefore;
                app.UnbleachingResults.avgAfter = allAvgAfterItrial;
                app.UnbleachingResults.avgWhole = allAvgAfterWhole;
                app.UnbleachingResults.fits = allFits;
                app.UnbleachingResults.allCoeffs = allCoeffs;

                numStacks = length(unbleachedImages);

                f.Value = .2;
                f.Message = 'Calculating DF and DF/F Images';
                [app.deltaFStacks, app.baselinesStack, app.deltaFOverFstacks] = deal(cell(numStacks, 1));
                for i = 1: numStacks
                    [app.deltaFStacks{i, 1}, app.deltaFOverFstacks{i, 1}, app.baselinesStack{i, 1}] =...
                        getDFMatrix(app.experimentType, unbleachedImages{i}, app.initFrames, app.endFrames);
                end
            else
                % Spontaneous events Experiment
                app.TimeLapseSlider.Visible = true;
                app.TimeLapseLabel.Visible = true;
                if nargin == 1
                    if ispc
                        workspace = 'data\Results Workspace.mat';
                    elseif ismac || isunix
                        workspace = 'data/Results Workspace.mat';
                    end
                    myVars = {'experiment', 'deconvStacks', 'regImagesCellArray', 'regCoeffs'};
                    load(workspace, myVars{:})

                    app.experimentName = experiment;
                    app.registeredStacks = regImagesCellArray;
                    app.regCoeffs = regCoeffs;
                    app.windowOffset = paramsMap('windowOffset');
                    app.windowLength = paramsMap('windowLength');
                    app.overlappedFrames = paramsMap('overlappedFrames');
                    app.stdFactor = paramsMap('stdFactor');
                    app.deconvStacks = concatDeconvStacks(app, deconvStacks, length(deconvStacks), app.overlappedFrames);
                else
                    app.experimentName = vararguin{1};
                    app.registeredStacks = vararguin{6};
                    app.unusualAmpInitFrames = vararguin{3};
                    app.initFrames = vararguin{4};
                    app.endFrames = vararguin{5};
                    app.regCoeffs = vararguin{7};
                    app.deconvStacks = concatDeconvStacks(app, vararguin{8}, length(vararguin{8}), app.overlappedFrames);
                end

                f.Value = .4;
                f.Message = 'Calculating DF and DF/F Images';
                [app.deltaFStacks, app.deltaFOverFstacks] =...
                    getDFMatrix(app.experimentType, app.registeredStacks, app.windowOffset, app.windowLength);
                numStacks = 1;
            end

            app.Results_AnalyzisIFigure.Name = app.experimentName + " - Results Processing";
            f.Value = .5;
            f.Message = 'Calculating Threshold';
            calculateThresholdValue(app);

            f.Value = .6;
            f.Message = 'Getting and clustering Release Events';
            app.EditFieldThresholdVal.Value = double(app.ThresholdVal);
            eventsPoints = getEvents(app.experimentType, app.deconvStacks, app.ThresholdVal, app.triggerTime);
            
            if ~isempty(app.regCoeffs)
                if app.experimentType == 0
                    eventsPoints = getEventsWithoutOutliers(app.experimentType, eventsPoints, app.regCoeffs, size(app.deconvStacks{1},1), [], []);
                else
                    eventsPoints = getEventsWithoutOutliers(app.experimentType, eventsPoints, app.regCoeffs, size(app.deconvStacks,1), app.windowOffset, app.windowLength);
                end
            else
                eventsPoints = getEventsWithoutOutliersFromRegistered(eventsPoints, app.registeredStacks, size(app.deconvStacks{1},1));
            end

            f.Value = .7;
            f.Message = 'Deleting False Positives (may take several seconds)';
            filteredStacks = cell(1); gsigma = app.gaussSigmaSA;
            FImages = app.deltaFStacks;
            parfor i = 1 : numStacks
                filteredStacks{i} = imgaussfilt(FImages{i}, gsigma);
            end
            trialsIndices = getCorrectPotentialEvents(filteredStacks, eventsPoints, app.initFrames, app.triggerTime, app.pseudoTriggerTime);
            for i = 1: numStacks
                eventsPoints{i} = eventsPoints{i}(logical(trialsIndices{i}), :);
            end

            app.releaseClusters = getClusterIndexes(eventsPoints, app.epsilonRC, app.minPts, 1, 'Release Clusters');
            app.centersOfGravityRC = getCentersOfGravity(app.releaseClusters);

            trialsList = strings(1,numStacks);
            for i = 1 : numStacks
                trialsList(i) = "Trial " + i;
            end
            app.TrialDropDown.Items = trialsList;
            app.TrialDropDown.ItemsData = (1:numStacks);
            app.TrialDropDown.Value = 1;
            
            temp = single(cat(3,app.registeredStacks{:}));
            temp(1:25, :, :) = NaN;
            temp(:, 1:25, :) = NaN;
            L = size(app.registeredStacks{1},1);
            temp(L-25:L, :, :) = NaN;
            temp(:, L-25:L, :) = NaN;

            app.MinimumBackgroundValueEditField.Value = double(min(temp, [], 'all'));

            f.Value = .9;
            f.Message = 'Showing data';
            plotReleaseEvents(app);
            set(app.Results_AnalyzisIFigure, 'pointer', 'arrow')
            close(f)


        end

        % Value changed function: TrialDropDown
        function TrialDropDownValueChanged(app, event)
            set(app.Results_AnalyzisIFigure, 'pointer', 'watch')
            f = uiprogressdlg(app.Results_AnalyzisIFigure, 'Title','Please Wait',...
                'Message', 'Ploting deconvolution results', 'Indeterminate','on');

            plotReleaseEvents(app);
            drawnow;
            set(app.Results_AnalyzisIFigure, 'pointer', 'arrow')
            close(f)
        end

        % Value changed function: TimeLapseSlider
        function TimeLapseSliderValueChanged(app, event)
            set(app.Results_AnalyzisIFigure, 'pointer', 'watch')
            f = uiprogressdlg(app.Results_AnalyzisIFigure, 'Title','Please Wait',...
                'Message', 'Ploting deconvolution results', 'Indeterminate','on');

            plotReleaseEvents(app);
            drawnow;
            set(app.Results_AnalyzisIFigure, 'pointer', 'arrow')
            close(f)

        end

        % Button pushed function: ButtonGetReleaseClusters
        function ButtonGetReleaseClustersPushed(app, event)
            set(app.Results_AnalyzisIFigure, 'pointer', 'watch')
            f = uiprogressdlg(app.Results_AnalyzisIFigure, 'Title','Please Wait',...
                'Message', 'Executing DBSCAN', 'Indeterminate','on');
            drawnow;

            calculateThresholdValue(app)
            app.EditFieldThresholdVal.Value = double(app.ThresholdVal);
            eventsPoints = getEvents(app.experimentType, app.deconvStacks, app.ThresholdVal, app.triggerTime);
            if ~isempty(app.regCoeffs)
                if app.experimentType == 0
                    eventsPoints = getEventsWithoutOutliers(app.experimentType, eventsPoints, app.regCoeffs, size(app.deconvStacks{1},1), [], []);
                else
                    eventsPoints = getEventsWithoutOutliers(app.experimentType, eventsPoints, app.regCoeffs, size(app.deconvStacks,1), app.windowOffset, app.windowLength);
                end
            else
                eventsPoints = getEventsWithoutOutliersFromRegistered(eventsPoints, app.registeredStacks, size(app.deconvStacks{1},1));
            end

            filteredStacks = cell(1); gsigma = app.gaussSigmaSA;
            FImages = app.deltaFStacks;
            parfor i = 1 : length(FImages)
                filteredStacks{i} = imgaussfilt(FImages{i}, gsigma);
            end
            trialsIndices = getCorrectPotentialEvents(filteredStacks, eventsPoints, app.initFrames, app.triggerTime, app.pseudoTriggerTime);
            for i = 1: length(FImages)
                eventsPoints{i} = eventsPoints{i}(logical(trialsIndices{i}), :);
            end

            app.releaseClusters = getClusterIndexes(eventsPoints, app.epsilonRC, app.minPts, 1, 'Release Clusters');
            app.centersOfGravityRC = getCentersOfGravity(app.releaseClusters);

            app.synapseClusters = [];
            app.selectedSynapses = [];
            app.isReleaseClustersUpdated = false;
            
            plotReleaseEvents(app);
            set(app.Results_AnalyzisIFigure, 'pointer', 'arrow')

            cla(app.AxesDeconvTraces);
            cla(app.AxesDFTraces);
            cla(app.AxesDFFTraces);
            cla(app.AxesXAxisTraces);
            cla(app.AxesYAxisTraces);
            cla(app.AxesSynapseClusters);
            cla(app.PeaksHistogramAxes);
            
            close(f)
        end

        % Button down function: AxesDBSCAN
        function AxesDBSCANButtonDown(app, event)
            A = get(app.AxesDBSCAN,'Children');
            set(A,'ButtonDownFcn', @plotProfilesAndImages)

            function plotProfilesAndImages(~, event)
                app.xClicked = round(event.IntersectionPoint(1),0);
                app.yClicked = round(event.IntersectionPoint(2),0);
                app.LabelX.Text = string(app.xClicked);
                app.LabelY.Text = string(app.yClicked);
                plotProfiles(app);
                plotMountedImages(app, event.IntersectionPoint(2), event.IntersectionPoint(1))
            end
        end

        % Value changed function: ROIRadiusSlider
        function ROIRadiusSliderValueChanged(app, event)
            set(app.Results_AnalyzisIFigure, 'pointer', 'watch')
            f = uiprogressdlg(app.Results_AnalyzisIFigure, 'Title','Please Wait',...
                'Message', 'Recalculating values', 'Indeterminate','on');
            drawnow;
            app.ROIRadiusSlider.Value = round(app.ROIRadiusSlider.Value, 0);
            plotProfiles(app)
            set(app.Results_AnalyzisIFigure, 'pointer', 'arrow')
            close(f)
        end

        % Button pushed function: ButtonGetSynapseClusters
        function ButtonGetSynapseClustersPushed(app, event)
            set(app.Results_AnalyzisIFigure, 'pointer', 'watch')
            f = uiprogressdlg(app.Results_AnalyzisIFigure, 'Title','Please Wait',...
                'Message', 'Getting synapse Clusters', 'Indeterminate','on');
            drawnow;
            if app.isReleaseClustersUpdated == true
                uialert(app.Results_AnalyzisIFigure,"Please extract the Release Clusters again","")
                cla(app.AxesSynapseClusters);
                cla(app.PeaksHistogramAxes);
                cla(app.AxesDBSCAN);
                app.synapseClusters = [];
                app.selectedSynapses = [];
                app.SelectedLabelVal.Text = "";
                app.DeletedLabelVal.Text = "";
                set(app.Results_AnalyzisIFigure, 'pointer', 'arrow')
                return;
            end

            [app.synapseClusters, app.releaseClusters] = getClusterIndexes(app.releaseClusters, app.epsilonSC, app.minPts, [], 'Synapse Clusters');
            [clusterAreas, clustersLabels] = getClustersAreas(app.releaseClusters, app.pixelFactorNm, app.pixelFactorNm*3, 'Release Clusters');
            [synapseAreas, synapseLabels] = getClustersAreas(app.synapseClusters, app.pixelFactorNm, app.pixelFactorNm*2, 'Synapse Clusters');
            [app.selectedSynapses, app.SynapsesCoG] = getSelectedSynapses(app.synapseClusters, app.radiusSC);
            [app.selectedSynapses, ~] = getReliableSynapses(app.selectedSynapses, clusterAreas, synapseAreas, clustersLabels, synapseLabels);
            
            app.isReleaseClustersUpdated = true;
            plotDFFPeaksHistogram(app)
            plotSynapseClusters(app, app.SynapsesCoG)
            app.SelectedLabelVal.Text = string(length(app.selectedSynapses.CoG));
            app.DeletedLabelVal.Text = string(length(unique(app.synapseClusters(:,1))) - length(app.selectedSynapses.CoG));
            set(app.Results_AnalyzisIFigure, 'pointer', 'arrow')
            close(f)
        end

        % Value changed function: EstablishBackgroundManuallyCheckBox
        function EstablishBackgroundManuallyCheckBoxValueChanged(app, event)
            if app.EstablishBackgroundManuallyCheckBox.Value
                uialert(app.Results_AnalyzisIFigure,'The Background value will afect the overall brigthness of the images and may affect the real amplitude of the signals',...
                'Remember...', 'Icon','info');
                app.manualMinBackgroundValField.Enable = true;
            else
                app.manualMinBackgroundValField.Enable = false;
            end
        end

        % Button pushed function: ButtonResults
        function ButtonResultsPushed(app, event)
            if ~isempty(app.selectedSynapses)
                set(app.Results_AnalyzisIFigure, 'pointer', 'watch')
                f = uiprogressdlg(app.Results_AnalyzisIFigure, 'Title','Please wait...',...
                    'Message', 'Exporting results');
                app.Results_AnalyzisIFigure.Visible = 'off';
                app.Results_AnalyzisIFigure.Visible = 'on';
                if ismac || isunix
                    divisor = "/";
                elseif ispc
                    divisor = "\";
                end
                drawnow;

                f.Value = .2;
                f.Message = 'Substracting Background';
                if ~app.EstablishBackgroundManuallyCheckBox.Value
                    [FImages, app.Backgrs, app.vertices] = getImagesWithoutBackground(app.registeredStacks');
                else
                    app.Backgrs = app.manualMinBackgroundValField.Value;
                    FImages = cellfun(@(x) x - app.Backgrs, app.registeredStacks, 'un', 0);
                end

                f.Value = .3;
                f.Message = 'Correcting Noise with Gaussian Filter';
                numStacks = length(app.registeredStacks);
                filteredStacks = cell(1); gsigma = app.gaussSigmaSA;
                parfor i = 1 : numStacks
                    filteredStacks{i} = imgaussfilt(FImages{i}, gsigma);
                end
                ROIs = getROIsFromSelectedSynapses(filteredStacks, app.selectedSynapses);

                f.Value = .4;
                f.Message = 'Getting and Normalizing ROIs traces';   
                
                [unbleachedROIs, app.unbleachedNormalizedROIs, ROIsFitCoeffs] = getUnbleachedNormalizedROis(ROIs, app.unusualAmpInitFrames, app.triggerTime);
                [app.unbleachedNormalizedROIs, unbleachedROIs] = getROIsAverages(app, app.unbleachedNormalizedROIs, unbleachedROIs);

                f.Value = .6;
                f.Message = 'Exporting CSV files';
                selpath = uigetdir(matlabroot,'Select a folder to save results'); % Path were all results will be saved
                selpath = selpath + divisor + app.experimentName + divisor;
                mkdir(selpath)
                app.Results_AnalyzisIFigure.Visible = 'off';
                app.Results_AnalyzisIFigure.Visible = 'on';

                % 1. ROIs information exported
                exportResultsToCSV(app.releaseClusters, app.selectedSynapses, ROIs, unbleachedROIs, app.unbleachedNormalizedROIs, ROIsFitCoeffs, selpath, numStacks)
                % 2. DF, DF/F and Deconvolved Stacks are saved
                if app.ExportProcessedStacksCheckBox.Value
                    exportToTIF(app.deconvStacks, string(selpath)+divisor, "0. Images\Deconv Stacks", divisor+"Dec ")
                    exportToTIF(app.deltaFStacks, string(selpath)+divisor, "0. Images\DF Stacks", divisor+"DF ")
                    exportToTIF(app.deltaFOverFstacks, string(selpath)+divisor, "0. Images\DFF Stacks", divisor+"DFF ")
                    exportToTIF(app.registeredStacks, string(selpath)+divisor, "0. Images\Registered Stacks", divisor+"Reg ")
                end
                % 3. Export Metadata and Images
                f.Value = .8;
                f.Message = 'Exporting Metadata and Images';
                if ~app.EstablishBackgroundManuallyCheckBox.Value
                    exportMetadata(selpath, app.experimentName, string(app.EditFieldThresholdFactor.Value), app.ThresholdVal, ...
                        app.vertices, app.Backgrs, app.unusualAmpInitFrames, getParametersMap())
                else
                    exportMetadata(selpath, app.experimentName, string(app.EditFieldThresholdFactor.Value), app.ThresholdVal, ...
                        [], string(app.Backgrs), app.unusualAmpInitFrames, getParametersMap())
                end
                exportImages(app, selpath);
                f.Value = 1;

                set(app.Results_AnalyzisIFigure, 'pointer', 'arrow')
                close(f)
                msgbox("Results succesuffuly exported", "Process Ready")
            else
                warndlg("No Synapse Clusters Calculated Yet", "Synapses List Is Empty")
            end
        end

        % Close request function: Results_AnalyzisIFigure
        function Results_AnalyzisIFigureCloseRequest(app, event)
            delete(app.Results_AnalyzisIFigure)
            delete(app)
            app.delete
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Results_AnalyzisIFigure = uifigure('Visible', 'off');
            app.Results_AnalyzisIFigure.Name = 'MATLAB App';
            app.Results_AnalyzisIFigure.CloseRequestFcn = createCallbackFcn(app, @Results_AnalyzisIFigureCloseRequest, true);
            app.ResponsesHistogramPanel = uipanel(app.Results_AnalyzisIFigure);
            app.ResponsesHistogramPanel.Title = 'Responses Histogram';
            app.PeaksHistogramAxes = uiaxes(app.ResponsesHistogramPanel);
            title(app.PeaksHistogramAxes, 'DF/F Peaks Histogram')
            xlabel(app.PeaksHistogramAxes, 'X')
            ylabel(app.PeaksHistogramAxes, 'Y')
            zlabel(app.PeaksHistogramAxes, 'Z')
            app.MeanEditFieldLabel = uilabel(app.ResponsesHistogramPanel);
            app.MeanEditFieldLabel.Text = 'Mean:';
            app.MeanEditField = uieditfield(app.ResponsesHistogramPanel, 'numeric');
            app.MeanEditField.Editable = 'off';
            app.STDEditFieldLabel = uilabel(app.ResponsesHistogramPanel);
            app.STDEditFieldLabel.Text = 'STD:';
            app.STDEditField = uieditfield(app.ResponsesHistogramPanel, 'numeric');
            app.STDEditField.Editable = 'off';
            app.ReleaseClustersPanel = uipanel(app.Results_AnalyzisIFigure);
            app.ReleaseClustersPanel.Title = 'Release Clusters';
            app.AxesDBSCAN = uiaxes(app.ReleaseClustersPanel);
            title(app.AxesDBSCAN, 'Release events')
            xlabel(app.AxesDBSCAN, 'X')
            ylabel(app.AxesDBSCAN, 'Y')
            zlabel(app.AxesDBSCAN, 'Z')
            app.AxesDBSCAN.ButtonDownFcn = createCallbackFcn(app, @AxesDBSCANButtonDown, true);
            app.AxesSynapseClusters = uiaxes(app.ReleaseClustersPanel);
            title(app.AxesSynapseClusters, 'Synapses')
            xlabel(app.AxesSynapseClusters, 'X')
            ylabel(app.AxesSynapseClusters, 'Y')
            zlabel(app.AxesSynapseClusters, 'Z')
            app.TrialDropDownLabel = uilabel(app.ReleaseClustersPanel);
            app.TrialDropDownLabel.Visible = 'off';
            app.TrialDropDownLabel.Text = 'Trial';
            app.TrialDropDown = uidropdown(app.ReleaseClustersPanel);
            app.TrialDropDown.ValueChangedFcn = createCallbackFcn(app, @TrialDropDownValueChanged, true);
            app.TrialDropDown.Visible = 'off';
            app.ButtonGetReleaseClusters = uibutton(app.ReleaseClustersPanel, 'push');
            app.ButtonGetReleaseClusters.ButtonPushedFcn = createCallbackFcn(app, @ButtonGetReleaseClustersPushed, true);
            app.ButtonGetReleaseClusters.Text = 'Get Release Clusters';
            app.EditFieldThresholdVal = uieditfield(app.ReleaseClustersPanel, 'numeric');
            app.EditFieldThresholdVal.Editable = 'off';
            app.ThresholdValueLabel = uilabel(app.ReleaseClustersPanel);
            app.ThresholdValueLabel.Text = 'Threshold Value';
            app.XLabel = uilabel(app.ReleaseClustersPanel);
            app.XLabel.Text = 'X:';
            app.LabelX = uilabel(app.ReleaseClustersPanel);
            app.LabelX.Text = '';
            app.YLabel = uilabel(app.ReleaseClustersPanel);
            app.YLabel.Text = 'Y:';
            app.LabelY = uilabel(app.ReleaseClustersPanel);
            app.LabelY.Text = '';
            app.ThresholdFactorEditFieldLabel = uilabel(app.ReleaseClustersPanel);
            app.ThresholdFactorEditFieldLabel.Text = 'Threshold Factor';
            app.EditFieldThresholdFactor = uieditfield(app.ReleaseClustersPanel, 'numeric');
            app.TimeLapseSlider = uislider(app.ReleaseClustersPanel);
            app.TimeLapseSlider.Limits = [200 5800];
            app.TimeLapseSlider.MajorTicks = [200 1000 2000 3000 4000 5000 5800];
            app.TimeLapseSlider.ValueChangedFcn = createCallbackFcn(app, @TimeLapseSliderValueChanged, true);
            app.TimeLapseSlider.Visible = 'off';
            app.TimeLapseSlider.Value = 200;
            app.TimeLapseLabel = uilabel(app.ReleaseClustersPanel);
            app.TimeLapseLabel.Visible = 'off';
            app.TimeLapseLabel.Text = 'Time Lapse';
            app.ButtonGetSynapseClusters = uibutton(app.ReleaseClustersPanel, 'push');
            app.ButtonGetSynapseClusters.ButtonPushedFcn = createCallbackFcn(app, @ButtonGetSynapseClustersPushed, true);
            app.ButtonGetSynapseClusters.Text = 'Get Synapse Clusters';
            app.SelectedLabel = uilabel(app.ReleaseClustersPanel);
            app.SelectedLabel.Text = 'Selected';
            app.SelectedLabelVal = uilabel(app.ReleaseClustersPanel);
            app.SelectedLabelVal.Text = '';
            app.DiscardedLabel = uilabel(app.ReleaseClustersPanel);
            app.DiscardedLabel.Text = 'Discarded';
            app.DeletedLabelVal = uilabel(app.ReleaseClustersPanel);
            app.DeletedLabelVal.Text = '';
            app.SignalsProfilesPanel = uipanel(app.Results_AnalyzisIFigure);
            app.SignalsProfilesPanel.Title = 'Signals Profiles';
            app.AxesDFTraces = uiaxes(app.SignalsProfilesPanel);
            title(app.AxesDFTraces, 'ΔF Temporal Profile')
            xlabel(app.AxesDFTraces, 'Time')
            ylabel(app.AxesDFTraces, 'Amplitude')
            zlabel(app.AxesDFTraces, 'Z')
            app.AxesXAxisTraces = uiaxes(app.SignalsProfilesPanel);
            title(app.AxesXAxisTraces, 'X-Axis Profile')
            xlabel(app.AxesXAxisTraces, 'X')
            ylabel(app.AxesXAxisTraces, 'Amplitude')
            zlabel(app.AxesXAxisTraces, 'Z')
            app.AxesDFFTraces = uiaxes(app.SignalsProfilesPanel);
            title(app.AxesDFFTraces, 'ΔF/F Temporal Profile')
            xlabel(app.AxesDFFTraces, 'Time')
            ylabel(app.AxesDFFTraces, 'Amplitude')
            zlabel(app.AxesDFFTraces, 'Z')
            app.AxesDeconvTraces = uiaxes(app.SignalsProfilesPanel);
            title(app.AxesDeconvTraces, 'Deconvolution Temporal Profile')
            xlabel(app.AxesDeconvTraces, 'Time')
            ylabel(app.AxesDeconvTraces, 'Amplitude')
            zlabel(app.AxesDeconvTraces, 'Z')
            app.AxesYAxisTraces = uiaxes(app.SignalsProfilesPanel);
            title(app.AxesYAxisTraces, 'Y-Axis Profile')
            xlabel(app.AxesYAxisTraces, 'Y')
            ylabel(app.AxesYAxisTraces, 'Amplitude')
            zlabel(app.AxesYAxisTraces, 'Z')
            app.ROIRadiusSliderLabel = uilabel(app.SignalsProfilesPanel);
            app.ROIRadiusSliderLabel.Text = 'ROI Radius';
            app.ROIRadiusSlider = uislider(app.SignalsProfilesPanel);
            app.ROIRadiusSlider.Limits = [0 4];
            app.ROIRadiusSlider.MajorTicks = [0 1 2 3 4];
            app.ROIRadiusSlider.ValueChangedFcn = createCallbackFcn(app, @ROIRadiusSliderValueChanged, true);
            app.ROIRadiusSlider.MinorTicks = [];
            app.SignalspertrialPanel = uipanel(app.Results_AnalyzisIFigure);
            app.SignalspertrialPanel.Title = 'Signals per trial';
            app.AxesSignalsImgs = uiaxes(app.SignalspertrialPanel);
            app.ResultsPanel = uipanel(app.Results_AnalyzisIFigure);
            app.ResultsPanel.Title = 'Results';
            app.ButtonResults = uibutton(app.ResultsPanel, 'push');
            app.ButtonResults.ButtonPushedFcn = createCallbackFcn(app, @ButtonResultsPushed, true);
            app.ButtonResults.Text = 'EXPORT RESULTS';
            app.ExportProcessedStacksCheckBox = uicheckbox(app.ResultsPanel);
            app.ExportProcessedStacksCheckBox.Text = 'Export Processed Stacks';
            app.BackgroundPanel = uipanel(app.Results_AnalyzisIFigure);
            app.BackgroundPanel.Title = 'Background';
            app.EstablishBackgroundManuallyCheckBox = uicheckbox(app.BackgroundPanel);
            app.EstablishBackgroundManuallyCheckBox.ValueChangedFcn = createCallbackFcn(app, @EstablishBackgroundManuallyCheckBoxValueChanged, true);
            app.EstablishBackgroundManuallyCheckBox.Text = 'Establish Background Manually';
            app.manualMinBackgroundValField = uieditfield(app.BackgroundPanel, 'numeric');
            app.manualMinBackgroundValField.Enable = 'off';
            app.MinimumBackgroundValueEditFieldLabel = uilabel(app.BackgroundPanel);
            app.MinimumBackgroundValueEditFieldLabel.Text = 'Minimum Background Value';
            app.MinimumBackgroundValueEditField = uieditfield(app.BackgroundPanel, 'numeric');
            app.MinimumBackgroundValueEditField.Editable = 'off';
            % Show the figure after all components are created
            app.Results_AnalyzisIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Results_Analyzis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Results_AnalyzisIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Results_AnalyzisIFigure)
        end
    end
end